classdef clinic_test0401 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup2                   matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        Gauge_SVM                   matlab.ui.control.LinearGauge
        svmGaugeLabel               matlab.ui.control.Label
        UIAxes_EMG_5                matlab.ui.control.UIAxes
        UIAxes_EMG_trigger          matlab.ui.control.UIAxes
        UIAxes_EMG_4                matlab.ui.control.UIAxes
        UIAxes_EMG_3                matlab.ui.control.UIAxes
        UIAxes_EMG_2                matlab.ui.control.UIAxes
        UIAxes_EMG_1                matlab.ui.control.UIAxes
        Tab_2                       matlab.ui.container.Tab
        Lamp_1                      matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_4                      matlab.ui.control.Lamp
        Lamp_5                      matlab.ui.control.Lamp
        Lamp_6                      matlab.ui.control.Lamp
        Lamp_7                      matlab.ui.control.Lamp
        Lamp_8                      matlab.ui.control.Lamp
        Stretch_State               matlab.ui.control.Label
        Heat_Press                  matlab.ui.control.UIAxes
        Stretch_pos                 matlab.ui.control.UIAxes
        Stretch_force               matlab.ui.control.UIAxes
        Label_State                 matlab.ui.control.Label
        Label_UpdateData            matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        Label_12                    matlab.ui.control.Label
        Button_MsgPatient           matlab.ui.control.Button
        Label_Tips                  matlab.ui.control.Label
        Panel_MotorUIAxes           matlab.ui.container.Panel
        Label_MotorDegree           matlab.ui.control.Label
        UIAxes_Speed                matlab.ui.control.UIAxes
        UIAxes_Degree               matlab.ui.control.UIAxes
        UIAxes_Current              matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        Tab_TwoMotor                matlab.ui.container.Tab
        Panel_DeviceOpra            matlab.ui.container.Panel
        Button_Finish               matlab.ui.control.Button
        Button_InitAll              matlab.ui.control.Button
        Button_CloseDevice          matlab.ui.control.Button
        Panel_RangeDetect           matlab.ui.container.Panel
        Label_MaxDegree             matlab.ui.control.Label
        Button_MaxAngle_After       matlab.ui.control.Button
        Button_MaxAngle_Before      matlab.ui.control.Button
        Panel_Calibration           matlab.ui.container.Panel
        Label_DegreeCalib           matlab.ui.control.Label
        Label_DegreePlus            matlab.ui.control.Label
        Button_DegreePlus           matlab.ui.control.Button
        EditField_StartDegreeCalib  matlab.ui.control.NumericEditField
        Label_14                    matlab.ui.control.Label
        Button_StartCalib           matlab.ui.control.Button
        Panel_TrainSet              matlab.ui.container.Panel
        DropDown_RestTime           matlab.ui.control.DropDown
        Label_22                    matlab.ui.control.Label
        DropDown_TractionTime       matlab.ui.control.DropDown
        Label_21                    matlab.ui.control.Label
        DropDown_TractionForce      matlab.ui.control.DropDown
        Label_20                    matlab.ui.control.Label
        DropDown_KeepTime           matlab.ui.control.DropDown
        Label_19                    matlab.ui.control.Label
        DropDown_AgainstPower       matlab.ui.control.DropDown
        Label_17                    matlab.ui.control.Label
        DropDown_TrainMode          matlab.ui.control.DropDown
        Label_16                    matlab.ui.control.Label
        DropDown_Speed              matlab.ui.control.DropDown
        Label_11                    matlab.ui.control.Label
        DropDown_Times              matlab.ui.control.DropDown
        Button_TrainStart           matlab.ui.control.Button
        EditField_EndDegree         matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        EditField_StartDegree       matlab.ui.control.NumericEditField
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Tab_Opensignal              matlab.ui.container.Tab
        Label_OP_ID                 matlab.ui.control.Label
        Label_StateOP               matlab.ui.control.Label
        Button_InitOP               matlab.ui.control.Button
        Panel_OP_Message            matlab.ui.container.Panel
        TextArea_receive            matlab.ui.control.TextArea
        TextArea_send               matlab.ui.control.TextArea
        TextArea_config             matlab.ui.control.TextArea
        Label_receive               matlab.ui.control.Label
        Label_send                  matlab.ui.control.Label
        Label_config                matlab.ui.control.Label
        ButtonGroup_OP_OPRA         matlab.ui.container.ButtonGroup
        Button_update_config        matlab.ui.control.Button
        Button_send                 matlab.ui.control.Button
        SelectButton_devices        matlab.ui.control.RadioButton
        SelectButton_config         matlab.ui.control.RadioButton
        SelectButton_enable         matlab.ui.control.RadioButton
        SelectButton_disable        matlab.ui.control.RadioButton
        Tab_3                       matlab.ui.container.Tab
        Button_stretch_4            matlab.ui.control.Button
        Button_stretch_3            matlab.ui.control.Button
        Button_stretch_2            matlab.ui.control.Button
        Button_stretch              matlab.ui.control.Button
        Button_StretchMinus_2       matlab.ui.control.Button
        Button_StretchPlus_2        matlab.ui.control.Button
        ButtonGroup_2               matlab.ui.container.ButtonGroup
        Button_6                    matlab.ui.control.ToggleButton
        Button_5                    matlab.ui.control.ToggleButton
        Button_4                    matlab.ui.control.ToggleButton
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Button_3                    matlab.ui.control.ToggleButton
        Button_2                    matlab.ui.control.ToggleButton
        Button                      matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        % 标定最大角度范围
        max_degree
        % 拉伸参数
        Com_stretch
        stopflag = ""

        % 患者信息
        DialogApp
        Patient = []

        % 通用参数
        IsPain = 0 % 疼痛标签 是否为疼痛状态
        IsStop = 0 % 停止标记 是否停止
        IsPlus = 0 % 增加电机角度标记 是否增加角度
        IsOP = 0 % 肌电仪标记 是否连接肌电仪
        IsInit = 0 %初始化标记 是否初始哈
        name_save_mat 
        name_save_all
        path_SaveFolder
        % 大然电机
        dr % 对象名称
        id = [1,2] % 电机id
        f  = 10 % 电机发送轨迹跟随频率


        % 电机状态信息
        message_motor = struct("temperature",[],"current",[],"speed",[] ,"degree",[]);
        % 电机当前状态
        state_now_motor = struct("temperature",[0,0],"torque",[0,0],"speed",[0,0],"degree",[0,0])
        machine_maxdegree
        state_degree_start % 自适应起始角度
        state_degree_end % 自适应终止角度
        Train_times = 0 % 训练次数
        Calib_times = 0 % 标定次数
        Adapt_times = 0 % 自适应训练次数
        Train_data % 训练数据
        Calib_data % 标定数据
        Adapt_data % 自适应训练数据
        Train_config
        Calib_config

        keep_total_save
        fis = mamfis('Name', 'AngleControl');

        Window_data % 实时窗数据
        % 康复参数
        Data_motor_1 = []
        Data_motor_2 = []


        % OP参数
        op_clent % tcp/ip客户端
        commands_op % 客户端向op发送的指令
        receives_op % 客户端从op接收到的数据
        Data_op = [];
        active
        config_op = struct( "ID",'',...
            "activeChannels",[], ...
            "samplingFreq",0, ...
            "labelChannels",[], ...
            "sensorChannels",[] ...
            )

        C = []
        S = []
        Model %svm疼痛检测模型

        % 绘图参数
        Buff_motor = zeros(500,6);
        Buff_op = zeros(5000,6);
        Count_op

        data_pos=[]; % 记录4组推杆的位置
        data_press=[];% 压力传感器数据
        % Com_stretch
        % stopflag=''
        stop_flag=0;
        inflation_flag=0;
        Press_well=[6,6,6,6,6,6,6,6];
        Press_limit=[15,15,15,15,15,15,15,15];
        
        Buff_motor2=zeros(100,8); %推杆数据
        Buff_motor3=zeros(100,8); %传感器数据
        name_save
        pos_direct

        image_exoskeleton
        actuators_max_movment=0;
    end

    properties (Access = public)
    end

    methods (Access = private)
        % 电机角度转换为四杆机构角度
        function angle_output=motor2linkage(app,angle_input)
            l1=43.6;
            l2=60.6;
            l3=48.5;
            l4=24.6;
            phi=pi/3;

            angle2rad=pi/180;
            rad2angle=180/pi;

            theta0=(angle_input+3)*angle2rad;
            l_BD=sqrt(l1^2+l4^2-2*l1*l4*cos(phi+theta0));
            theta1=asin((l1*sin(phi+theta0))/l_BD);
            theta2=acos((l_BD^2+l3^2-l2^2)/(2*l_BD*l3));
            phi3=theta1+theta2-(pi-phi);
            phi2=asin((l1*sin(theta0)+l4*sin(phi)+l3*sin(phi3))/l2);
            angle_output=90-(phi2*rad2angle);
            app.Label_MotorDegree.Text = strcat("当前角度：",string(angle_output));
        end

        %  四杆机构角度转换为电机角度
        function angle_output=linkage2motor(app,angle_input)
            motor_angle=1:180;
            linkage_angle=[0.975843406238283	1.84353832467785	2.69832422248591	3.54066256064978	4.37099891655988	5.18976353935132	5.99737189708415	6.79422521394369	7.58071099603211	8.35720354460096	9.12406445585637	9.88164310667889	10.6302771258035	11.3702928501584	12.1020057662025	12.8257209362168	13.5417334096008	14.2503286193016	14.9517827635742	15.6463631733213	16.3343286653056	17.0159298815597	17.6914096153455	18.3610031240350	19.0249384292994	19.6834366049979	20.3367120531687	20.9849727685229	21.6284205918412	22.2672514526704	22.9016556017136	23.5318178332961	24.1579176982867	24.7801297078401	25.3986235283216	26.0135641677583	26.6251121541574	27.2334237060154	27.8386508953345	28.4409418034501	29.0404406699613	29.6372880350473	30.2316208754397	30.8235727343114	31.4132738453324	32.0008512511310	32.5864289163927	33.1701278358137	33.7520661371242	34.3323591793815	34.9111196467274	35.4884576377961	36.0644807509504	36.6392941655172	37.2130007191844	37.7857009817181	38.3574933251481	38.9284739905673	39.4987371516823	40.0683749752482	40.6374776785158	41.2061335838115	41.7744291703700	42.3424491235320	42.9102763814162	43.4779921791702	44.0456760909029	44.6134060693949	45.1812584836831	45.7493081546095	46.3176283884241	46.8862910085258	47.4553663854276	48.0249234650238	48.5950297952398	49.1657515511397	49.7371535585672	50.3092993163913	50.8822510174272	51.4560695681038	52.0308146069410	52.6065445219069	53.1833164667163	53.7611863761350	54.3402089803503	54.9204378184699	55.5019252512059	56.0847224728036	56.6688795222710	57.2544452939643	57.8414675475840	58.4299929176348	59.0200669224007	59.6117339724868	60.2050373789768	60.8000193612550	61.3967210545401	61.9951825171760	62.5954427377256	63.1975396419095	63.8015100994338	64.4073899307451	65.0152139137545	65.6250157905678	66.2368282742576	66.8506830557139	67.4666108106056	68.0846412064859	68.7048029100711	69.3271235947227	69.9516299481595	70.5783476804256	71.2073015321382	71.8385152830377	72.4720117608606	73.1078128505545	73.7459395038519	74.3864117492194	75.0292487021949	75.6744685761267	76.3220886933236	76.9721254966260	77.6245945614052	78.2795106079966	78.9368875145709	79.5967383304473	80.2590752898481	80.9239098260972	81.5912525862590	82.2611134462162	82.9335015261838	83.6084252066514	84.2858921447517	84.9659092910449	85.6484829067139	86.3336185811602	87.0213212499907	87.7115952133881	88.4044441548507	89.0998711602943	89.7978787375016	90.4984688359103	91.2016428667260	91.9074017233496	92.6157458021065	93.3266750232656	94.0401888523378	94.7562863216407	95.4749660521214	96.1962262754236	96.9200648561922	97.6464793146043	98.3754668491177	99.1070243594318	99.8411484696502	100.577835551643	101.317081748600	102.058882998776	102.803235059420	103.550133530890	104.299573880958	105.051551469295	105.806061572150	106.563099407225	107.322660158747	108.084739002754	108.849331132592	109.616431784649	110.386036264330	111.158139972291	111.932738430948	112.709827311287	113.489402459988	114.271459926895	115.055995992856	115.843007197961	116.632490370212	117.424442654661	118.218861543055	119.015744904022];
            [~,num]=min(abs(angle_input-linkage_angle));
            angle_output=motor_angle(num);
        end

        % 电机绘图
        function PlotData_motor(app)
            len = min(size(app.Data_motor_1,1),size(app.Data_motor_2,1));
            if len >= 500
                app.Buff_motor = [flip(app.Data_motor_1(end-499:end,1:3)),-1 * flip(app.Data_motor_2(end-499:end,1:3))];
            else                
                Z = zeros(500-len,6);
                app.Buff_motor = [[flip(app.Data_motor_1(1:len,1:3)),-1 * flip(app.Data_motor_2(1:len,1:3))];Z];
            end
            plot(app.UIAxes_Current,app.Buff_motor(:,[3,6]));
            plot(app.UIAxes_Speed,app.Buff_motor(:,[2,5]));
            plot(app.UIAxes_Degree,app.Buff_motor(:,[1,4]));
        end

        % 保存数据设置（确定保存文件名"采样时间点"）
        function Save_dataset(app)
            fullpath = mfilename('fullpath');%获取当前m文件的绝对路径，不包含文件名后缀
            [path_,name,ext] = fileparts(fullpath);%将绝对路径拆分，path为当前工作路径；name为当前运行文件名；ext为文件名后缀
            pathname = strcat(app.path_SaveFolder,'\');
            %pathname = strcat(path_,'\',app.path_SaveFolder,'\');
            time_now = string(datetime("now","InputFormat","uuuuMMdd'T'HHmmss"),"uuuu年MM月dd日HH时mm分ss秒");
            app.name_save_mat = strcat(pathname,time_now,'.mat');
            app.name_save_all = strcat(pathname,time_now,'.mat');
        end
        % 保存数据
        function Save_data_part(app)

            Data_save{1} = app.Data_motor_1;
            Data_save{2} = app.Data_motor_2;
            Data_save{3} = app.Data_op;
            Data_save{4} = app.Train_config;
            name = strcat(app.name_save_mat,'-train_times_',num2str(app.Train_times));
            save(name,"Data_save");
        end

        function Save_data_all(app)

            train_data = app.Train_data;
            train_config = app.Train_config;

            calib_data = app.Calib_data;
            calib_config = app.Calib_config;

            patient = app.Patient;

            save(strcat(app.name_save_all,app.Patient.name),"train_data","train_config", ...
                                                            "calib_data","calib_config", ...
                                                            "patient");
        end

        % op生理信号采集相关函数

        % 初始化，连接op，确认op的ID
        function init_clent(app)
            app.op_clent = tcpclient('127.0.0.1',5555);
            pause(1);
            write(app.op_clent,'devices');
            pause(0.5);
            text = read(app.op_clent);
            if isempty(text)
                app.Label_State.Text = "无法接收到数据,检查op，尝试重新连接";
                pause(1);
                error('error');
            else
                text = char(text);
                app.TextArea_receive.Value = text;
                app.config_op.ID = app.TextArea_receive.Value{1}(end-19:end-3);
                app.Label_OP_ID.Text = app.config_op.ID;
            end
        end
        % 向op发送指令
        function send_command(app)
            if app.IsOP == 1
                write(app.op_clent,app.commands_op);
                app.Label_State.Text = "发送完成";
            end
        end
        % 从op接收数据
        function receive_command(app)
            if app.IsOP == 1
                app.receives_op = char(read(app.op_clent));
                app.Label_State.Text = "接收完成";
            end
        end
        % 更新op采样配置至matlab
        function config_update(app)
            if app.IsOP == 1
                command = "config";
                text = app.TextArea_receive.Value{1};
    
                if command == "config" && length(text) > 100
                    app.Label_State.Text = "可更新";
                    pos = strfind(text,'"activeChannels": ');
                    app.config_op.activeChannels = str2num(text(pos+18:pos+41)); %18为'"activeChannels": '的长度，41为18加数据长度
    
                    pos = strfind(text,'"samplingFreq": ');
                    app.config_op.samplingFreq = str2num(text(pos+16:pos+19)); %16为'"samplingFreq": '的长度，19为16加数据长度
    
                    pos = strfind(text,'"labelChannels": ');
                    pos_end = strfind(text,']');
                    pos_end1 = find(pos_end > pos);
                    app.config_op.labelChannels = split(erase(text(pos+18:pos_end(pos_end1(1))-1),' ')); %16为'"samplingFreq": '的长度，19为16加数据长度
    
                    pos = strfind(text,'"sensorChannels": ');
                    pos_end = strfind(text,']');
                    pos_end1 = find(pos_end > pos);
                    app.config_op.sensorChannels = split(erase(text(pos+19:pos_end(pos_end1(1))-1),' ')); %16为'"samplingFreq": '的长度，19为16加数据长度
    
                    app.TextArea_config.Value = ["activeChannels:",num2str(app.config_op.activeChannels),"samplingFreq:",num2str(app.config_op.samplingFreq),"sensorChannels:",app.config_op.sensorChannels];
    
                    app.config_op.labelChannels = split(string(app.config_op.labelChannels),',');
                    app.config_op.sensorChannels = split(string(app.config_op.sensorChannels),',');
                else
                    app.Label_State.Text = "无法更新";
                    return;
                end
    
                app.active =  find(app.config_op.activeChannels == 1);
                title_UI = strcat(app.config_op.labelChannels,app.config_op.sensorChannels);
                for i = 1 : length(app.active)
                    app.("UIAxes_EMG_" + num2str(i)).Title.String = char(title_UI(app.active(i)));
                end
            end
        end

        % 接收op采样数据开启阶段（需要等待几秒才能开始采集）
        function receive_data_op_pre(app)
            if app.IsOP == 1
                app.Data_op = [];
                app.Count_op = 0;
                app.commands_op = 'start';
                send_command(app);
                pause(0.5);
                app.receives_op = char(read(app.op_clent));
                while app.op_clent.NumBytesAvailable == 0
                end
            end
        end

        function receive_data_op_clearBuff(app)
            if app.IsOP == 1
                app.Data_op = [];
                app.Count_op = 0;
                app.receives_op = char(read(app.op_clent));
                app.receives_op = [];           
            end

        end


        function [predict_label,accy,svmrst] = receive_data_op(app)
            if app.IsOP == 1
                predict_label = nan; accy = nan; svmrst = nan;           
                if app.op_clent.NumBytesAvailable ~= 0
                    data = process_data_op(app); % 将采集到的文本格式的数据转化为double格式
                    if ~isempty(app.Model)
                        [predict_label,accy,svmrst] = Predict_WindowData(app,data);
                    end
                    app.Data_op = [app.Data_op;data];
                    PlotData_op(app,data);
                end
            else
                predict_label = nan; accy = nan; svmrst = nan;
            end
            
        end

        function data = process_data_op(app)

            app.receives_op = char(read(app.op_clent));
            app.receives_op = erase(app.receives_op,'{"returnCode": 0, "returnData": {"00:07:80:4B:29:56":');
            app.receives_op = erase(app.receives_op,'}}');

            data_ = str2num(app.receives_op);
            data__ = reshape(data_,length(app.active)+2,[]);
            data = data__';

            trigger = data(:,2);
            if max(trigger) == 1
                app.IsStop = 1;
            else
                app.IsStop = 0;
            end

            % 添加疼痛标签
            label = zeros(size(data,1),1);
            label(:) = app.IsPain;
            data = [data,label];


        end

        function [predict_label,accy,svmrst] = Predict_WindowData(app,Data)
            data = Data(:,[3:6,end]);
            % data_1 = normalize(abs(data(:,1:4)),"center",app.C,"scale",app.S);
            data_1 = [data(:,1:4),data(:,end)];


            feature(1,1:4) = mean(abs(data_1(:,1:4))); % 均值
            feature(1,5:8) = std(data_1(:,1:4)); % 标准差
            feature(1,9:12) = rms(data_1(:,1:4)); % 均方根
            feature(1,13:16) = sum(abs(diff(data_1(:,1:4))))/size(data,1); % 波长
            feature(1,17:20) = bandpower(data_1(:,1:4),2000,[20,500]); % band power
            % feature(1,21:24) = meanfreq(data_1(:,1:4),2000); % 平均频率
            label(1) = max(data_1(:,end));
            if label == -1
                predict_label = 0;
                accy = 0;
                svmrst = 0;
            end

            [predict_label,accy,svmrst] = svmpredict(label,feature,app.Model);

        end

        function PlotData_op(app,data)
            if app.IsOP == 1
                [L,CH] = size(data);
                app.Count_op = app.Count_op + L;
    
                for i = 1 : CH-2
                    app.Buff_op(:,i) = [flip(data(:,i+2));app.Buff_op(1:end-L,i)];
                end
                app.Buff_op(:,6) = [flip(data(:,2));app.Buff_op(1:end-L,6)];
    
                for i = 1 : CH-2
                    plot(app.("UIAxes_EMG_" + num2str(i)),app.Buff_op(:,i));
                    %app.("UIAxes_EMG_" + num2str(i+3)).XTickLabel = linspace(roundn(app.Count_op/2000,-1),roundn((app.Count_op-1000)/2000,-1),6);
                end
                plot(app.UIAxes_EMG_trigger,app.Buff_op(:,6));
                %app.UIAxes_EMG_trigger.XTickLabel = linspace(roundn(app.Count_op/2000,-1),roundn((app.Count_op-1000)/2000,-1),6);                
            end

        end


        % 功能执行过程中使能关闭
        function EnableOff(app)
            app.Button_InitAll.Enable = 'off';
            app.Panel_RangeDetect.Enable = 'off';
            app.Panel_Calibration.Enable = 'off';
            app.Panel_TrainSet.Enable = 'off';           
            app.Button_MsgPatient.Enable = 'off';
        end

        % 功能执行过程中使能打开
        function EnableOn(app)
            app.Button_InitAll.Enable = 'on';
            app.Panel_RangeDetect.Enable = 'on';
            app.Panel_Calibration.Enable = 'on';
            app.Panel_TrainSet.Enable = 'on';
            app.Button_MsgPatient.Enable = 'on';
        end


        function model = TrainingModel(app,Data)
            if app.IsOP == 1
                data = Data(:,[3:6,end]);
                % [data_1,app.C,app.S] = normalize(abs(data(:,1:4)),"range");
                data_1 = [data(:,1:4),data(:,end)];
    
                %设置分析窗
                Slwindow_L = 500;
                Slwindow_step = 250;
                Slwindow_start = 1;
                Slwindow_end = Slwindow_L;
                Slwindow_data = zeros(Slwindow_L,1);
    
                L_data = size(data_1,1);
                step_count = ceil((L_data-Slwindow_L)/Slwindow_step);
                feature = zeros(step_count,20);
    
                for step = 0:step_count -1
                    Slwindow_data = data_1(Slwindow_start:Slwindow_end,1:4);
                    Slwindow_label = data_1(Slwindow_start:Slwindow_end,end);
                    feature(step+1,1:4) = mean(abs(Slwindow_data)); % 均值
                    feature(step+1,5:8) = std(Slwindow_data); % 标准差
                    feature(step+1,9:12) = rms(Slwindow_data); % 均方根
                    feature(step+1,13:16) = sum(abs(diff(Slwindow_data)))/Slwindow_L; % 波长
                    feature(step+1,17:20) = bandpower(Slwindow_data,2000,[20,500]); % band power
                    % feature(step+1,21:24) = medfreq(Slwindow_data,2000); % 平均频率

                    label(step+1,1) = max(Slwindow_label);
                    Slwindow_start = Slwindow_start + Slwindow_step;   %窗步进
                    Slwindow_end = Slwindow_end + Slwindow_step;       %窗步进
                end
    
                feature_T = feature((label == 0 | label == 1),:);
                label_T = label(label == 0 | label == 1);
                paramlibsvm=['-t ',num2str(0),' -q '];
                model = svmtrain(label_T, feature_T, paramlibsvm);
            else
                model = [];
            end
        end


        function callbackFcn(app,~,~)
            result = app.dr.angle_speed_torque_states(app.id);

            label1 = zeros(size(result{1},1),1);
            label1(:) = app.IsPain;
            result{1} = [result{1},label1];

            label2 = zeros(size(result{2},1),1);
            label2(:) = app.IsPain;
            result{2} = [result{2},label2];

            app.Data_motor_1 = [app.Data_motor_1;result{1}];
            app.Data_motor_2 = [app.Data_motor_2;result{2}];

            app.state_now_motor.degree = [app.Data_motor_1(end,1),app.Data_motor_2(end,1)];
            app.state_now_motor.speed = [app.Data_motor_1(end,2),app.Data_motor_2(end,2)];
            app.state_now_motor.torque = [app.Data_motor_1(end,3),app.Data_motor_2(end,3)];
            degree = mean(abs(app.state_now_motor.degree));
            motor2linkage(app,degree);
            PlotData_motor(app);
        end


        % 被动训练
        function train_mode_1(app)
            train_mode = app.DropDown_TrainMode.Value;
            times = str2double(app.DropDown_Times.Value);
            speed_level = app.DropDown_Speed.Value;            
            degree_start_ = app.EditField_StartDegree.Value;           
            degree_end_ = app.EditField_EndDegree.Value; 
            degree_start = linkage2motor(app,degree_start_);
            degree_end = linkage2motor(app,degree_end_);
            T_keep = str2double(app.DropDown_KeepTime.Value);
            T_rest = str2double(app.DropDown_RestTime.Value);
            app.Train_config{app.Train_times} = struct("train_mode",train_mode, ...
                                                       "times",times, ...
                                                       "speed",speed_level, ...
                                                       "degree_start",[degree_start_,degree_start], ...
                                                       "degree_end",[degree_end_,degree_end], ...
                                                       "keep_time",T_keep, ...
                                                       "rest_time",T_rest); 
            
            % 速度三档调节            
            switch speed_level
                case "缓慢"
                    T = 20;
                case "适中"
                    T = 15;
                case "快速"
                    T = 10;
            end
            
            % 轨迹设置
            x = 0:0.1:T+1;

            % degree_go = (degree_end-degree_start)/2 * cos(pi/T * x + pi) + (degree_end+degree_start)/2;
            trace = app.Seg_trace_result(T, degree_start, degree_end);
            degree_go = [trace,ones(1,10)*trace(end)];

            % 准备开始
            app.dr.enable_angle_speed_torque_state(1); % 开启实时反馈使能
            app.dr.enable_angle_speed_torque_state(2); % 开启实时反馈使能
            receive_data_op_clearBuff(app);
            % 训练循环
            for i = 1 : times
                app.Label_Tips.Text = ['第',num2str(i),'次训练'];
            % 屈曲运动                
                tic;
                app.Label_State.Text = "开始";
                while 1
                    pause(0.1);
                    t = ceil(toc*10);
                    target_angle = degree_go(t);
                    app.dr.set_angles([1,2], [target_angle,-target_angle], 10, app.f/2, 0);
                    [~,~,svmrst] = receive_data_op(app);
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                        disp(-svmrst);
                    end
                    if (app.IsStop == 1) || (toc >= T)
                        app.dr.estop(0);
                        app.IsPain = 1;
                        app.IsStop = 0;
                        break;
                    end
                end                
            % 到达设定位置，保持T_keep秒
                app.Label_State.Text = "保持";
                tic
                while (toc < T_keep) 
                    [~,~,svmrst] = receive_data_op(app);
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                        disp(-svmrst);
                    end
                    pause(0.1);
                end
                
                degree_stop = round(mean(abs(app.state_now_motor.degree)));
                % degree_back = (degree_stop-degree_start)/2 * cos(pi/T * x ) + (degree_stop+degree_start)/2;
                degree_back = [trace(end:-1:1), ones(1,10)*trace(1)];
                % 伸展运动
                app.IsPain = -1;
                tic;
                app.Label_State.Text = "结束";
                while toc < T
                    pause(0.1);
                    t = ceil(toc*10);
                    target_angle = degree_back(t);
                    app.dr.set_angles([1,2], [target_angle,-target_angle], 10, app.f/2, 0);
                    [~,~,svmrst] = receive_data_op(app);
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                        disp(-svmrst);
                    end
                end            
            % 到达初始位置，休息T_rest秒
                app.IsPain = 0;
                app.Label_State.Text = "休息";
                tic
                while toc < T_rest
                    [~,~,svmrst] = receive_data_op(app);
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                        disp(-svmrst);
                    end
                    pause(0.1);
                end

            end
            pause(1);
            app.dr.disable_angle_speed_torque_state(1);
            app.dr.disable_angle_speed_torque_state(2);
            flush(app.dr.dev);
        end

        % 对抗训练
        function train_mode_2(app)
            train_mode = app.DropDown_TrainMode.Value;
            times = str2double(app.DropDown_Times.Value);
            speed_level = app.DropDown_Speed.Value;            
            degree_start_ = app.EditField_StartDegree.Value;           
            degree_end_ = app.EditField_EndDegree.Value; 
            degree_start = linkage2motor(app,degree_start_);
            degree_end = linkage2motor(app,degree_end_);
            against_power = app.DropDown_AgainstPower.Value;
            T_keep = str2double(app.DropDown_KeepTime.Value);
            T_rest = str2double(app.DropDown_RestTime.Value);
            app.Train_config{app.Train_times} = struct("train_mode",train_mode, ...
                                                       "times",times, ...
                                                       "speed",speed_level, ...
                                                       "degree_start",[degree_start_,degree_start], ...
                                                       "degree_end",[degree_end_,degree_end], ...
                                                       "keep_time",T_keep, ...
                                                       "rest_time",T_rest, ...
                                                       "against_power",against_power); 
            
            % 速度三档调节            
            switch speed_level
                case "缓慢"
                    T = 20;
                case "适中"
                    T = 15;
                case "快速"
                    T = 10;
            end
            switch against_power
                case "较小"
                    ag_pw = 1;
                case "适中"
                    ag_pw = 1.5;
                case "较大"
                    ag_pw = 2;
            end
            
            % 轨迹设置
            x = 0:0.1:T+1;
            % degree_go = (degree_end-degree_start)/2 * cos(pi/T * x + pi) + (degree_end+degree_start)/2;
            trace = app.Seg_trace_result(T, degree_start, degree_end);
            degree_go = [trace,ones(1,10)*trace(end)];
            % 准备开始
            app.dr.enable_angle_speed_torque_state(1); % 开启实时反馈使能
            app.dr.enable_angle_speed_torque_state(2); % 开启实时反馈使能
            receive_data_op_clearBuff(app);
            % 训练循环
            for i = 1 : times
                app.Label_Tips.Text = ['第',num2str(i),'次训练'];
            % 屈曲运动                
                tic;
                app.Label_State.Text = "开始";
                while 1
                    pause(0.1);
                    t = ceil(toc*10);
                    target_angle = degree_go(t);
                    app.dr.set_angles([1,2], [target_angle,-target_angle], 10, app.f/2, 0);
                    [~,~,~] = receive_data_op(app);
                    disp(t);
                    if (app.IsStop == 1) || (toc >= T)
                        app.dr.estop(0);
                        app.IsPain = 1;
                        app.IsStop = 0;
                        break;
                    end
                end                
            % 到达设定位置，保持10s
                app.Label_State.Text = "对抗";
                app.dr.impedance_control_multi([1,2], app.state_now_motor.degree, [1,1], app.state_now_motor.torque, [ag_pw,ag_pw], [0.1,0.1]);
                
                tic
                while (toc < T_keep) 
                    [~,~,~] = receive_data_op(app);
                    pause(0.1);
                end
                app.dr.estop(0);
                
                degree_stop = round(mean(abs(app.state_now_motor.degree)));
                % degree_back = (degree_stop-degree_start)/2 * cos(pi/T * x ) + (degree_stop+degree_start)/2;    
                degree_back = [trace(end:-1:1), ones(1,10)*trace(1)];
            % 伸展运动
                app.IsPain = -1;
                tic;
                app.Label_State.Text = "结束";
                while toc < T
                    pause(0.1);
                    t = ceil(toc*10);
                    target_angle = degree_back(t);
                    app.dr.set_angles([1,2], [target_angle,-target_angle], 10, app.f/2, 0);
                    [~,~,~] = receive_data_op(app);
                    disp(t);
                end            
            % 到达初始位置，休息5s
                app.IsPain = 0;
                app.Label_State.Text = "休息";
                tic
                while toc < T_rest
                    [~,~,~] = receive_data_op(app);
                    pause(0.1);
                end

            end
            pause(1);
            app.dr.disable_angle_speed_torque_state(1);
            app.dr.disable_angle_speed_torque_state(2);
            flush(app.dr.dev);
        end
        
        function fuzzy_control(app)
            

            % 添加输入变量 疼痛程度
            app.fis = addInput(app.fis, [-1 1], 'Name', 'PainLevel'); % 输入范围为0到100摄氏度
            % 添加隶属度函数 - 不疼--很疼
            app.fis = addMF(app.fis, 'PainLevel', 'zmf', [-1 -0.5], 'Name', 'NP');
            app.fis = addMF(app.fis, 'PainLevel', 'trimf', [-1 -0.5 0], 'Name', 'SP');
            app.fis = addMF(app.fis, 'PainLevel', 'trimf', [-0.5 0 0.5], 'Name', 'P');
            app.fis = addMF(app.fis, 'PainLevel', 'trimf', [0 0.5 1], 'Name', 'MP');
            app.fis = addMF(app.fis, 'PainLevel', 'smf', [0.5 1], 'Name', 'VP');

            % 添加输入变量 关节阻力
            app.fis = addInput(app.fis, [0 1], 'Name', 'JointResistance'); % 输入范围为0到100摄氏度
            % 添加隶属度函数 - 无阻力--大阻力
            app.fis = addMF(app.fis, 'JointResistance', 'zmf', [0 0.25], 'Name', 'NR');
            app.fis = addMF(app.fis, 'JointResistance', 'trimf', [0 0.25 0.5], 'Name', 'SR');
            app.fis = addMF(app.fis, 'JointResistance', 'trimf', [0.25 0.5 0.75], 'Name', 'R');
            app.fis = addMF(app.fis, 'JointResistance', 'trimf', [0.5 0.75 1], 'Name', 'MR');
            app.fis = addMF(app.fis, 'JointResistance', 'smf', [0.75 1], 'Name', 'BR');

            % 添加输出变量 - 加热器强度
            app.fis = addOutput(app.fis, [-6 6], 'Name', 'angle'); % 输出范围为0到1
            % 添加隶属度函数 - 低、中、高
            app.fis = addMF(app.fis, 'angle', 'zmf', [-6 -3], 'Name', 'BM');
            app.fis = addMF(app.fis, 'angle', 'trimf', [-6 -3 0], 'Name', 'SM');
            app.fis = addMF(app.fis, 'angle', 'trimf', [-3 0 3], 'Name', 'K');
            app.fis = addMF(app.fis, 'angle', 'trimf', [0 3 6], 'Name', 'SA');
            app.fis = addMF(app.fis, 'angle', 'smf', [3 6], 'Name', 'BA');


            % 定义模糊规则（使用数值矩阵）
            rules = [
                1 1 5 1 1; % JointResistance=NR, PainLevel=NP => angle=BA
                1 2 5 1 1; % JointResistance=NR, PainLevel=SP => angle=BA
                1 3 4 1 1; % JointResistance=NR, PainLevel=P => angle=SA
                1 4 3 1 1; % JointResistance=NR, PainLevel=MP => angle=SM
                1 5 3 1 1; % JointResistance=NR, PainLevel=VP => angle=SM

                2 1 5 1 1; % JointResistance=SR, PainLevel=NP => angle=BA
                2 2 4 1 1; % JointResistance=SR, PainLevel=SP => angle=SA
                2 3 3 1 1; % JointResistance=SR, PainLevel=P => angle=K
                2 4 3 1 1; % JointResistance=SR, PainLevel=MP => angle=K
                2 5 3 1 1; % JointResistance=SR, PainLevel=VP => angle=SM

                3 1 5 1 1; % JointResistance=R, PainLevel=NP => angle=BA
                3 2 4 1 1; % JointResistance=R, PainLevel=SP => angle=SA
                3 3 3 1 1; % JointResistance=R, PainLevel=P => angle=K
                3 4 3 1 1; % JointResistance=R, PainLevel=MP => angle=SM
                3 5 2 1 1; % JointResistance=R, PainLevel=VP => angle=BM

                4 1 4 1 1; % JointResistance=MR, PainLevel=NP => angle=SA
                4 2 3 1 1; % JointResistance=MR, PainLevel=SP => angle=K
                4 3 3 1 1; % JointResistance=MR, PainLevel=P => angle=K
                4 4 3 1 1; % JointResistance=MR, PainLevel=MP => angle=SM
                4 5 2 1 1; % JointResistance=MR, PainLevel=VP => angle=BM

                5 1 3 1 1; % JointResistance=BR, PainLevel=NP => angle=SM
                5 2 3 1 1; % JointResistance=BR, PainLevel=SP => angle=SM
                5 3 3 1 1; % JointResistance=BR, PainLevel=P => angle=SM
                5 4 2 1 1; % JointResistance=BR, PainLevel=MP => angle=BM
                5 5 2 1 1  % JointResistance=BR, PainLevel=VP => angle=BM
                ];
            app.fis = addRule(app.fis, rules);
        end

        % 自适应被动训练
        function train_mode_3(app)
            add_degree = [5.1487827 0.58418626 1.1090648 -2.4133179 -1.4525875 0.35588762 0.74423593 -3.0000007 3.0027387 3.9916439];
            train_mode = app.DropDown_TrainMode.Value;
            times = str2double(app.DropDown_Times.Value);
            speed_level = app.DropDown_Speed.Value;            
            degree_start_ = app.EditField_StartDegree.Value;            
            degree_end_ = app.state_degree_end;   
            degree_start = linkage2motor(app,degree_start_);
            degree_end = linkage2motor(app,degree_end_);
            T_keep = str2double(app.DropDown_KeepTime.Value);
            T_rest = str2double(app.DropDown_RestTime.Value);
            app.Train_config{app.Train_times} = struct("train_mode",train_mode, ...
                                                       "times",times, ...
                                                       "speed",speed_level, ...
                                                       "degree_start",[degree_start_,degree_start], ...
                                                       "degree_end",[degree_end_,degree_end], ...
                                                       "keep_time",T_keep, ...
                                                       "rest_time",T_rest, ...
                                                       "model",app.Model); 
            
            % 速度三档调节            
            switch speed_level
                case "缓慢"
                    T = 20;
                case "适中"
                    T = 15;
                case "快速"
                    T = 10;
            end
            
            count_painlabel = 0;
            update_time = 5;
            keep_total = [0,0,0];
            app.keep_total_save = [];

            x = 0:0.1:T+1;
            
            app.dr.enable_angle_speed_torque_state(1); % 开启实时反馈使能
            app.dr.enable_angle_speed_torque_state(2); % 开启实时反馈使能
            receive_data_op_clearBuff(app);
         
            for i = 1 : times
                fz_in_force1 = [];
                fz_in_force2 = [];
                fz_in_pain = [];
                fz_input = [];

                trace = app.Seg_trace_result(T, degree_start, degree_end);
                degree_go = [trace,ones(1,10)*trace(end)];
                % degree_go = (degree_end-degree_start)/2 * cos(pi/T * x + pi) + (degree_end+degree_start)/2;
                app.Label_Tips.Text = strcat('第',num2str(i),'次训练,最大角度:',num2str(motor2linkage(app,degree_end)));
                app.IsPain = 0;               
                app.IsStop = 0;
                % 屈曲运动                
                tic;
                app.Label_State.Text = "开始";
                while 1
                    pause(0.1);
                    t = ceil(toc*10);
                    target_angle = degree_go(t);
                    app.dr.set_angles([1,2], [target_angle,-target_angle], 10, app.f/2, 0);
                    [~,~,svmrst] = receive_data_op(app);
                    if svmrst < 0 % svm输出阈值 低于0判断为疼痛
                        count_painlabel = count_painlabel + 1;
                    elseif svmrst >= 0
                        count_painlabel = 0;
                    end
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                        disp(-svmrst);
                    end

                    keep = [app.IsStop == 1,toc >= T,count_painlabel == 5];
                    if max(keep)
                        app.dr.estop(0);
                        keep_total = keep_total + keep;
                        app.IsStop = 0;
                        app.IsPain = 1;
                        count_painlabel = 0;
                        app.Label_UpdateData.Text = num2str(keep_total);
                        break;
                    end
                end                 

% 到达设定位置，保持10s
                app.Label_State.Text = "保持";
                tic
                while (toc < T_keep) 
                    [~,~,svmrst] = receive_data_op(app);
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                        fz_in_pain = [fz_in_pain, -svmrst];
                    end
                    
                    pause(0.1);
                end
                fz_in_force1 = abs(app.Data_motor_1(end-20*T_keep:end, 3));
                fz_in_force2 = abs(app.Data_motor_2(end-20*T_keep:end, 3));
                fz_input = [mean(fz_in_pain), (mean(fz_in_force1) + mean(fz_in_force2))/10];

                degree_stop = round(mean(abs(app.state_now_motor.degree)));
                trace = app.Seg_trace_result(T, degree_stop, degree_start);
                degree_back = [trace,ones(1,10)*trace(end)];
                % degree_back = (degree_stop-degree_start)/2 * cos(pi/T * x ) + (degree_stop+degree_start)/2;  


% 返回初始位置
                app.Label_State.Text = "结束";
                app.IsPain = -1;
                tic;
                while 1
                    pause(0.1);
                    t = ceil(toc*10);
                    target_angle = degree_back(t);
                    app.dr.set_angles([1,2], [target_angle,-target_angle], 10, app.f/2, 0);
                    [~,~,svmrst] = receive_data_op(app);
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                        disp(-svmrst);
                    end
                    
                    if (toc >= T)
                        app.dr.estop(0);
                        break;
                    end
                end 

% 到达初始位置，休息5s
                app.IsPain = 0;
                app.Label_State.Text = "休息";
                tic
                while toc < T_rest
                    pause(0.1);
                    [~,~,svmrst] = receive_data_op(app);
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                    end                    
                end 

                
                % outputAngle = evalfis(app.fis, fz_input);
                app.keep_total_save = [app.keep_total_save;[fz_input,outputAngle]];
                
                angle = motor2linkage(app,degree_end);
                angle = angle + add_degree(i); % outputAngle;
                degree_end = linkage2motor(app,angle);
                % if mod(i,update_time) == 0 % 每往返三次更新一次参数
                %     if keep_total(1) >= update_time - 1
                %         angle = motor2linkage(app,degree_end);
                %         angle = angle - 2;
                %         degree_end = linkage2motor(app,angle);
                %     end
                %     if keep_total(2) >= update_time - 1
                %         angle = motor2linkage(app,degree_end);
                %         angle = angle + 1;
                %         if angle > (app.machine_maxdegree - 0.5)
                %             angle = app.machine_maxdegree - 0.5;
                %         end
                %         degree_end = linkage2motor(app,angle);
                %     end                   
                %     keep_total = [0,0,0];
                % end
            end
            pause(1);
            app.dr.disable_angle_speed_torque_state(1);
            app.dr.disable_angle_speed_torque_state(2);
            flush(app.dr.dev);
            app.state_degree_end = motor2linkage(app,degree_end);
        end
        
        % 自适应对抗训练
        function train_mode_4(app)
            train_mode = app.DropDown_TrainMode.Value;
            times = str2double(app.DropDown_Times.Value);
            speed_level = app.DropDown_Speed.Value;            
            degree_start_ = app.EditField_StartDegree.Value;            
            degree_end_ = app.state_degree_end; 
            degree_start = linkage2motor(app,degree_start_);
            degree_end = linkage2motor(app,degree_end_);
            T_keep = str2double(app.DropDown_KeepTime.Value);
            T_rest = str2double(app.DropDown_RestTime.Value);
            against_power = app.DropDown_AgainstPower.Value;
            app.Train_config{app.Train_times} = struct("train_mode",train_mode, ...
                                                       "times",times, ...
                                                       "speed",speed_level, ...
                                                       "degree_start",[degree_start_,degree_start], ...
                                                       "degree_end",[degree_end_,degree_end], ...
                                                       "keep_time",T_keep, ...
                                                       "rest_time",T_rest, ...
                                                       "against_power",against_power, ...
                                                       "model",app.Model); 
            
            % 速度三档调节            
            switch speed_level
                case "缓慢"
                    T = 20;
                case "适中"
                    T = 15;
                case "快速"
                    T = 10;
            end

            switch against_power
                case "较小"
                    ag_pw = 1;
                case "适中"
                    ag_pw = 1.5;
                case "较大"
                    ag_pw = 2;
            end
            
            count_painlabel = 0;
            update_time = 5;
            keep_total = [0,0,0];
            app.keep_total_save = [];

            x = 0:0.1:T+1;
            
            app.dr.enable_angle_speed_torque_state(1); % 开启实时反馈使能
            app.dr.enable_angle_speed_torque_state(2); % 开启实时反馈使能
            receive_data_op_clearBuff(app);
         
            for i = 1 : times
                degree_go = (degree_end-degree_start)/2 * cos(pi/T * x + pi) + (degree_end+degree_start)/2;
                app.Label_Tips.Text = strcat('第',num2str(i),'次训练,最大角度:',num2str(motor2linkage(app,degree_end)));
                app.IsPain = 0;               
                app.IsStop = 0;

                % 屈曲运动                
                tic;
                app.Label_State.Text = "开始";
                while 1
                    pause(0.1);
                    t = ceil(toc*10);
                    target_angle = degree_go(t);
                    app.dr.set_angles([1,2], [target_angle,-target_angle], 10, app.f/2, 0);
                    [~,~,svmrst] = receive_data_op(app);
                    if svmrst < 0 % svm输出阈值 低于0.2判断为疼痛
                        count_painlabel = count_painlabel + 1;
                    elseif svmrst >= 0
                        count_painlabel = 0;
                    end
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                    end
                    disp(t);
                    keep = [app.IsStop == 1,toc >= T,count_painlabel == 5];
                    if max(keep)
                        app.dr.estop(0);
                        keep_total = keep_total + keep;
                        app.IsStop = 0;
                        app.IsPain = 1;
                        count_painlabel = 0;
                        app.Label_UpdateData.Text = num2str(keep_total);
                        break;
                    end
                end                 

% 到达设定位置，保持10s
                app.Label_State.Text = "对抗";
                app.dr.impedance_control_multi([1,2], app.state_now_motor.degree, [1,1], app.state_now_motor.torque, [ag_pw,ag_pw], [0.1,0.1]);
                tic
                while (toc < T_keep) 
                    [~,~,svmrst] = receive_data_op(app);
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                    end
                    pause(0.1);
                end

                degree_stop = round(mean(abs(app.state_now_motor.degree)));
                degree_back = (degree_stop-degree_start)/2 * cos(pi/T * x ) + (degree_stop+degree_start)/2;  
                
% 返回初始位置
                app.Label_State.Text = "结束";
                app.IsPain = -1;
                tic;
                while 1
                    pause(0.1);
                    t = ceil(toc*10);
                    target_angle = degree_back(t);
                    app.dr.set_angles([1,2], [target_angle,-target_angle], 10, app.f/2, 0);
                    [~,~,svmrst] = receive_data_op(app);
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                    end
                    disp(t);
                    if (toc >= T)
                        app.dr.estop(0);
                        break;
                    end
                end 

% 到达初始位置，休息5s
                app.IsPain = 0;
                app.Label_State.Text = "休息";
                tic
                while toc < T_rest
                    pause(0.1);
                    [~,~,svmrst] = receive_data_op(app);
                    if ~isnan(svmrst)
                        app.Gauge_SVM.Value = -svmrst;
                    end                    
                end 

                app.keep_total_save = [app.keep_total_save;keep_total];
                
                if mod(i,update_time) == 0 % 每往返三次更新一次参数
                    if keep_total(1) >= update_time - 1
                        angle = motor2linkage(app,degree_end);
                        angle = angle - 2;
                        degree_end = linkage2motor(app,angle);
                    end
                    if keep_total(2) >= update_time - 1
                        angle = motor2linkage(app,degree_end);
                        angle = angle + 1;
                        if angle > (app.machine_maxdegree - 0.5)
                            angle = app.machine_maxdegree - 0.5;
                        end
                        degree_end = linkage2motor(app,angle);
                    end                   
                    keep_total = [0,0,0];
                end
            end
            pause(1);
            app.dr.disable_angle_speed_torque_state(1);
            app.dr.disable_angle_speed_torque_state(2);
            flush(app.dr.dev);
            app.state_degree_end = motor2linkage(app,degree_end);
        end
        % 静态牵引训练
        function train_mode_5(app)
             app.IsStop = 0;
            % 设置拉伸参数
            train_mode = app.DropDown_TrainMode.Value;
            traction_force = app.DropDown_TractionForce.Value;
            traction_time = app.DropDown_TractionTime.Value;
            app.Train_config{app.Train_times} = struct("train_mode",train_mode, ...
                                                       "traction_force",traction_force, ...
                                                       "traction_time",traction_time); 
            %% 到达指定位置拉伸
            degree_end = app.EditField_EndDegree.Value;
            degree_end = linkage2motor(app,degree_end);
            % 回归初始位置
            app.dr.set_angles(app.id, [degree_end,-degree_end], 2, 1, 1);
            app.dr.positions_done(app.id);
            % 清空数据缓存
            flush(app.dr.dev);

            set_load = str2double(app.DropDown_TractionForce.Value);
            traction_keeptime = str2double(app.DropDown_TractionTime.Value);
            real_load=0;  %当前牵引力
            times_flag=0; %增加牵引行程的次数

            windowSize = 5;  %设置滤波参数
            b = (1/windowSize)*ones(1,windowSize);
            a=1;
            % 开启反馈静止10s
            write(app.Com_stretch,'o',"char"); % 开启反馈

            tic
            while (toc<10 && app.IsStop ==0)
                pause(0.01);
            end

            % 读取推杆初始状态
            initial_data=app.data_pos(end-49:end,:);
            initial_data_fit=filter(b,a,initial_data); %分别对8列初始数据均值滤波
            ini_force_vec=mean(initial_data_fit(:,5:8));%一行四个向量

            % 每6s检测一次力状态，未达阈值则增加行程
            while (real_load<set_load && times_flag < 3 &&  app.IsStop == 0)
                write(app.Com_stretch,'3',"char"); % 加
                write(app.Com_stretch,'3',"char"); % 加
                write(app.Com_stretch,'3',"char"); % 加
                times_flag=times_flag+1;                
                tic
                while (toc<6 && app.IsStop == 0)
                    pause(0.01);
                end

                % 每次循环中计算是否达到要求状态
                current_data=app.data_pos(end-49:end,:);
                current_data_fit=filter(b,a,current_data);
                cur_force_vec=mean(current_data_fit(:,5:8));% 结果为一行四个向量

                real_load=sum(cur_force_vec-ini_force_vec);

                % linshi=[times_flag,ini_force_vec, cur_force_vec,real_load];
                % disp(linshi);
            end
            
            % 达到设定力或最大行程保持10s
            tic
            while (toc<traction_keeptime &&  app.IsStop == 0)
                pause(0.01);
            end
            current_data=app.data_pos(end-49:end,:);
            current_data_fit=filter(b,a,current_data);
            app.actuators_max_movment=mean(current_data_fit(:,1:4));

            % 推杆归零
            write(app.Com_stretch,'1',"char"); % 归零
            tic
            while (toc<5 &&  app.IsStop == 0)
                pause(0.01);
            end

            % 结束Arduino读取并重置标志位
            write(app.Com_stretch,'c',"char"); % 结束反馈
            pause(0.5);
            app.stopflag=''; %重置标志位
            if (app.Com_stretch.NumBytesAvailable>0) %清空串口
                flush(app.Com_stretch);
            end
        end

        % 自适应充气功能
        function train_mode_6(app)
            app.IsStop = 0;
            % 设置拉伸参数
            write(app.Com_stretch,'o',"char"); % 开启反馈

            app.Stretch_State.Text='反馈开启';
            
            % 准备调整
            app.Stretch_State.Text = sprintf('准备中');
            tic
            while (toc<5 &&  app.IsStop == 0)
                % read_Arduino(app);
                % Plot_Stretch(app);
                % Plot_Sensor(app);
                pause(0.01);
            end

           % 大腿自适应充气
           app.Stretch_State.Text = sprintf('大腿充气');
           Thigh_limit=[9 9 5]; %大腿充气上限
           write(app.Com_stretch,'5',"char"); % 大腿充气
           tic
            while( app.IsStop == 0 && toc<8)
                inflation_time=toc;
                app.Stretch_State.Text = sprintf('充气时间：%f ',inflation_time);

                % 大腿充气判断
                cur_data_press=app.data_press(end-30:end,:); %传感器当前30点数据
                cur_press_vec=mean(cur_data_press); %平均后转换为向量
                Thigh_press=cur_press_vec(1:3); %得到大腿当前压力

                if sum(Thigh_press>=Thigh_limit)==3||sum(Thigh_press>app.Press_limit(1:3))>=1
                    Mark_press=Thigh_press;
                    break;
                end
            end
            write(app.Com_stretch,'6',"char"); % 大腿保持
            app.Stretch_State.Text = sprintf('大腿充气结束');
            tic
            while toc<1
                pause(0.01);
            end

            % 小腿自适应充气
            app.Stretch_State.Text = sprintf('小腿充气');
            calf_limit=[5 5 5 5]; %小腿充气上限
            write(app.Com_stretch,'8',"char"); % 小腿充气
            tic
            while( app.IsStop == 0 && toc<8)
                inflation_time=toc;
                app.Stretch_State.Text = sprintf('充气时间：%f ',inflation_time);

                % 小腿充气判断
                cur_data_press=app.data_press(end-30:end,:); %传感器当前30点数据
                cur_press_vec=mean(cur_data_press); %平均后转换为向量
                calf_press=cur_press_vec(5:8); %得到小腿前侧当前压力

                if sum(calf_press>=calf_limit)>=3||sum(calf_press>app.Press_limit(5:8))>=1
                    Mark_press=[Mark_press,calf_press];
                    break;
                end
            end
            

            write(app.Com_stretch,'9',"char"); % 小腿保持
            app.Stretch_State.Text = sprintf('小腿充气结束');
            
            % 充气完毕结束反馈
            write(app.Com_stretch,'c',"char"); % 结束反馈
            app.Stretch_State.Text=sprintf('充气完毕：用时 %f ',inflation_time);
            pause(0.5);
            app.stopflag=''; %重置标志位
            if (app.Com_stretch.NumBytesAvailable>0) %清空串口
                flush(app.Com_stretch);
            end
        end
        function callbackFcn2(app,~,~)       
            % 读取推杆数据
            for i=1:2
                readbuff=readline(app.Com_stretch);
                actuators_back=double(split(readbuff," "));
                app.data_pos=[app.data_pos;actuators_back'];
            % 读取压力传感器
                readbuff=readline(app.Com_stretch);
                sensor_back=double(split(readbuff," "));
                sensor_back=(sensor_back*9.8)/1000;
                app.data_press=[app.data_press;sensor_back'];
            end

            Plot_Stretch(app);
            Plot_Sensor(app);
            pause(0.01);

        end
        % 推杆绘图        
        function Plot_Stretch(app)

                app.Buff_motor2 =[flip(app.data_pos);app.Buff_motor2];                         
                plot(app.Stretch_pos,(flip(mean(app.Buff_motor2(1:100,1:4),2))),'LineWidth',3.5,'Color',[0.8500 0.3250 0.0980]);%[0 0.4470 0.7410]  [0.9290 0.6940 0.1250]
                hold(app.Stretch_pos,"on")
                plot(app.Stretch_pos,flip(app.Buff_motor2(1:100,1)));
                hold(app.Stretch_pos,"on")
                plot(app.Stretch_pos,flip(app.Buff_motor2(1:100,2)));
                hold(app.Stretch_pos,"on")
                plot(app.Stretch_pos,flip(app.Buff_motor2(1:100,3)));
                hold(app.Stretch_pos,"on")
                plot(app.Stretch_pos,flip(app.Buff_motor2(1:100,4)));
                hold(app.Stretch_pos,"off")

                % app.Buff_motor2 =flip(app.data_pos);                  
                plot(app.Stretch_force,(flip(sum(app.Buff_motor2(1:100,5:8),2))),'LineWidth',3.5,'Color',[0.8500 0.3250 0.0980]);%,'Marker','*'
                hold(app.Stretch_force,"on")
                plot(app.Stretch_force,flip(app.Buff_motor2(1:100,5)));
                hold(app.Stretch_force,"on")
                plot(app.Stretch_force,flip(app.Buff_motor2(1:100,6)));
                hold(app.Stretch_force,"on")
                plot(app.Stretch_force,flip(app.Buff_motor2(1:100,7)));
                hold(app.Stretch_force,"on")
                plot(app.Stretch_force,flip(app.Buff_motor2(1:100,8)));
                hold(app.Stretch_force,"off")

            % end
        end
                %% 传感器绘图        
        function Plot_Sensor(app)
            % i = length(app.data_press(:,1));
            % if i > 10

                % app.Buff_motor = [[flip(app.data_pos(:,1)),flip(app.data_pos(:,2)),flip(app.data_pos(:,3)),flip(app.data_pos(:,4))];app.Buff_motor(1:end-i,:)];
                app.Buff_motor3 =[flip(app.data_press);app.Buff_motor3];
                % plot(app.Press_Thigh_left,flip(app.Buff_motor3(1:100,1)));
                if(app.Buff_motor3(1,1)>=app.Press_limit(1))
                    app.Lamp_1.Color="1,0,0";
                elseif(app.Buff_motor3(1,1)>=app.Press_well(1))
                    app.Lamp_1.Color="0.00,0.4470,0.7410";
                else
                    app.Lamp_1.Color="0.40,0.90,0.00";
                end

                % plot(app.Press_Thigh_right,flip(app.Buff_motor3(1:100,2)));
                if(app.Buff_motor3(1,2)>=app.Press_limit(2))
                    app.Lamp_2.Color="1,0,0";
                elseif(app.Buff_motor3(1,2)>=app.Press_well(2))
                    app.Lamp_2.Color="0.00,0.4470,0.7410";
                else
                    app.Lamp_2.Color="0.40,0.90,0.00";
                end

                % plot(app.Press_Thigh_front,flip(app.Buff_motor3(1:100,3)));
                if(app.Buff_motor3(1,3)>=app.Press_limit(3))
                    app.Lamp_3.Color="1,0,0";
                elseif(app.Buff_motor3(1,3)>=app.Press_well(3))
                    app.Lamp_3.Color="0.00,0.4470,0.7410";
                else
                    app.Lamp_3.Color="0.40,0.90,0.00";
                end
                % plot(app.Press_Calf_back,flip(app.Buff_motor3(1:100,4)));
                if(app.Buff_motor3(1,4)>=app.Press_limit(4))
                    app.Lamp_4.Color="1,0,0";
                elseif(app.Buff_motor3(1,4)>=app.Press_well(4))
                    app.Lamp_4.Color="0.00,0.4470,0.7410";
                else
                    app.Lamp_4.Color="0.40,0.90,0.00";
                end
                % plot(app.Press_Calf_L_F,flip(app.Buff_motor3(1:100,5)));
                if(app.Buff_motor3(1,5)>=app.Press_limit(5))
                    app.Lamp_5.Color="1,0,0";
                elseif(app.Buff_motor3(1,5)>=app.Press_well(5))
                    app.Lamp_5.Color="0.00,0.4470,0.7410";
                else
                    app.Lamp_5.Color="0.40,0.90,0.00";
                end
                % plot(app.Press_Calf_L_B,flip(app.Buff_motor3(1:100,6)));
                if(app.Buff_motor3(1,6)>=app.Press_limit(6))
                    app.Lamp_6.Color="1,0,0";
                elseif(app.Buff_motor3(1,6)>=app.Press_well(6))
                    app.Lamp_6.Color="0.00,0.4470,0.7410";
                else
                    app.Lamp_6.Color="0.40,0.90,0.00";
                end
                % plot(app.Press_Calf_R_F,flip(app.Buff_motor3(1:100,7)));
                if(app.Buff_motor3(1,7)>=app.Press_limit(7))
                    app.Lamp_7.Color="1,0,0";
                elseif(app.Buff_motor3(1,7)>=app.Press_well(7))
                    app.Lamp_7.Color="0.00,0.4470,0.7410";
                else
                    app.Lamp_7.Color="0.40,0.90,0.00";
                end
                % plot(app.Press_Calf_R_B,flip(app.Buff_motor3(1:100,8)));
                if(app.Buff_motor3(1,8)>=app.Press_limit(8))
                    app.Lamp_8.Color="1,0,0";
                elseif(app.Buff_motor3(1,8)>=app.Press_well(8))
                    app.Lamp_8.Color="0.00,0.4470,0.7410";
                else
                    app.Lamp_8.Color="0.40,0.90,0.00";
                end

        end
                %% 传感器绘图
        function press_N=Trans_Press(app,read_data)
            voltage=(5000*read_data)/1024; %引脚换算电压
            press=(10000*voltage)/3300; %电压换算力（g）
            press_N=press*9.8/1000;  %力单位g换算为N
        end

        function read_Arduino(app)
            while(app.Com_stretch.NumBytesAvailable==0)
            end
            %% 读取推杆数据
            readbuff=readline(app.Com_stretch);
            app.stopflag=readbuff;
            if  (app.stopflag~="s t o p")
                actuators_back=double(split(readbuff," "));
                app.data_pos=[app.data_pos;actuators_back'];
            
                %% 读取压力传感器
                readbuff=readline(app.Com_stretch);
                sensor_back=double(split(readbuff," "));
                sensor_back=(sensor_back*9.8)/1000;
                app.data_press=[app.data_press;sensor_back'];
            end
        end
        
        function Trace = Seg_trace_result(app,T,Q0,Qf)
            % 时间参数
     
            T1 = 0.25*T; % 第一段五次多项式的时间长度
            T2 = 0.5*T; % 线性段的时间长度
            T3 = 0.25*T; % 第二段五次多项式的时间长度

            % 构建时间矩阵 T
            T11 = [T1^5, T1^4, T1^3, T1^2, T1;
                5*T1^4, 4*T1^3, 3*T1^2, 2*T1, 1;
                20*T1^3, 12*T1^2, 6*T1, 2, 0;
                60*T1^2, 24*T1, 6, 0, 0];

            T12 = [1, 0, -1, 0;
                0, -1, 0, 0;
                0, 0, 0, 0;
                0, 0, 0, 0];

            T22 = [0, T2, 1, 0;
                0, 1, 0, 0;
                0, 0, 0, 0;
                0, 0, 0, 0;
                0, 0, 0, T3^5];

            T23 = [0, 0, 0, 0, -1;
                0, 0, 0, -1, 0;
                0, 0, -2, 0, 0;
                0, -6, 0, 0, 0;
                T3^4, T3^3, T3^2, T3, 1];

            T31 = [0, 0, 0, 0, 0;
                0, 0, 0, 0, 0;
                0, 0, 0, 0, 0;
                0, 0, 0, 0, 1;
                0, 0, 0, 2, 0];

            T32 = [0, 0, 0, 5*T3^4;
                0, 0, 0, 20*T3^3;
                1, 0, 0, 0;
                0, 0, 0, 0;
                0, 0, 0, 0];

            T33 = [4*T3^3, 3*T3^2, 2*T3, 1, 0;
                12*T3^2, 6*T3, 2, 0, 0;
                0, 0, 0, 0, 0;
                0, 0, 0, 0, 0;
                0, 0, 0, 0, 0];

            % 构建总的时间矩阵 T
            T = [T11, T12, zeros(4,5);
                zeros(5,5), T22, T23;
                T31, T32, T33];

            % 构建位置向量 theta
            theta = [0, 0, 0, 0, 0, 0, 0, 0, Qf, 0, 0, Q0, 0, 0]';
            % 求解系数矩阵 B
            B = T \ theta;

            % 提取各段的系数
            coeffs1 = B(1:6)';
            coeffs2 = B(7:8)';
            coeffs3 = B(9:14)';

            % 生成轨迹
            t1 = linspace(0.1, T1, round(10*T1));
            t2 = linspace(T1+0.1, T1 + T2, round(10*T2));
            t3 = linspace(T1 + T2+0.1, T1 + T2 + T3, round(10*T3));

            trace1 = polyval(coeffs1, t1);
            trace2 = polyval(coeffs2, t2 - T1); % 调整时间偏移
            trace3 = polyval(coeffs3, t3 - (T1 + T2));

            Trace = [trace1,trace2,trace3];
        end
    end

    methods (Access = public)

        function msg_patient_save(app,patient)
            app.Patient = patient;
            app.path_SaveFolder = strcat('Save_Data\',app.Patient.date,app.Patient.name);
            mkdir(app.path_SaveFolder);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             img = imread('Exoskeleton.jpg'); % 替换为你的图片文件路径
%             % app.image_exoskeleton = image(img);
%             imshow(img,'Parent',app.Heat_Press);
%             axis(app.Heat_Press, 'image'); % 设置轴为图像大小
%             xlim(app.Heat_Press, [0 size(img, 2)]); % 调整 X 轴的范围
%             ylim(app.Heat_Press, [0 size(img, 1)]); % 调整 Y 轴的范围

            app.Label_Tips.Text = "训练开始前请点击‘患者信息登记’进行信息登记";
            uart_baudrate = 115200;
            app.dr = DrEmpower_can("COM4", uart_baudrate);
            % 清除缓存
            flush(app.dr.dev);
            % 电机卸载
            app.dr.set_mode(0, 1);
            pause(0.5);
            % 电机待机
            app.dr.set_mode(0, 2);

            app.dr.disable_angle_speed_torque_state(1);
            app.dr.disable_angle_speed_torque_state(2);

            app.dr.set_state_feedback_rate_ms(1, 50);
            app.dr.set_state_feedback_rate_ms(2, 50);

            app.dr.set_torque_limit(0,54);
            app.dr.set_speed_limit(0,5);


            configureCallback(app.dr.dev,"byte",256,@app.callbackFcn);


        end

        % Button pushed function: Button_MsgPatient
        function Button_MsgPatientPushed(app, event)
            app.Button_MsgPatient.Enable = "off";
            app.DialogApp = msg_patient(app);            
        end

        % Button pushed function: Button_InitAll
        function Button_InitAllPushed(app, event)
            app.Label_Tips.Text = "启动中";
            EnableOff(app);

            
%             % 打开串口
%             try
%                 app.Com_stretch=serialport("COM10",9600,"Timeout",5);
%                 % app.Drive_State.Text = [sprintf('\n串口打开成功\n')];
%                 app.Stretch_State.Text = '串口打开成功';
%             catch
%                 % app.Drive_State.Text = [sprintf('\n串口打开失败\n')];
%                 app.Stretch_State.Text = '串口打开失败';
%                 delete(app.Com_stretch);
%             end
%             configureTerminator(app.Com_stretch,"CR/LF");
%             if (app.Com_stretch.NumBytesAvailable>0)
%                 flush(app.Com_stretch);
%             end

%             %pause(8);
%             %configureCallback(app.Com_stretch,"byte",62,@app.callbackFcn2);
%             configureCallback(app.Com_stretch,"byte",125,@app.callbackFcn2);
% 

            % 初始化op
            
            try
                init_clent(app);
                app.IsOP = 1;
            catch
                app.Label_Tips.Text = "未连接肌电仪，部分功能无法使用";
                app.Label_StateOP.Text = "未连接肌电仪，部分功能无法使用";
                app.IsOP = 0;
                pause(2);
            end
            app.Label_StateOP.Text = "初始化完成";

            % 更新op配置
            pause(0.5);
            if app.IsOP == 1
                app.commands_op = strcat('config',',',app.config_op.ID);
                app.TextArea_send.Value{1} = '';
                app.TextArea_send.Value = app.commands_op;
                send_command(app);
                pause(0.1);
                receive_command(app);
                app.TextArea_receive.Value{1} = '';
                app.TextArea_receive.Value = app.receives_op;
                config_update(app);
            end

            % 初始化(启动)CAN控制盒

            app.Label_Tips.Text = "设 备 启 动 中..";
            pause(1)
            app.Label_Tips.Text = "设 备 启 动 中...";
            pause(1)


            % 设备最大物理角度检测
            app.Label_State.Text = "2.设备运动检测";
            app.Label_Tips.Text = "2秒后将进行设备运动检测，请做好准备";
            pause(2);
            app.Label_Tips.Text = (['开始检测设备是否存在卡顿',newline,'请将设备屈曲至最大范围，完成后点击【结束】']);
            
            app.dr.set_mode(0, 1);
            pause(0.5);
            app.dr.enable_angle_speed_torque_state(1);
            app.dr.enable_angle_speed_torque_state(2);
            maxdegree = 0;
            while 1
                if mean(abs(app.state_now_motor.degree)) > maxdegree
                    maxdegree = mean(abs(app.state_now_motor.degree));
                end
                if app.IsStop == 1
                    maxdegree = floor(motor2linkage(app,maxdegree)); % 设置设备最大屈曲角度
                    maxdegree = double(maxdegree);
                    app.EditField_EndDegree.Limits = [5,maxdegree];
                    app.EditField_StartDegree.Limits = [5,maxdegree];
                    app.machine_maxdegree = maxdegree;
                    break;
                end
                pause(0.01);
            end
            app.IsStop = 0;
            app.dr.disable_angle_speed_torque_state(1);
            app.dr.disable_angle_speed_torque_state(2);
            flush(app.dr.dev);
            

            app.Label_Tips.Text = "设备运动检测结束";
            pause(1);
            app.Label_Tips.Text = "设备已启动，请患者穿戴好设备";
            receive_data_op_pre(app);
            pause(1);
            app.Label_State.Text = "设备已就绪";
            pause(1);
            app.Label_Tips.Text = "请点击‘训练前屈曲角度检测’";
            
            
            EnableOn(app);
  
        end

        % Button pushed function: Button_CloseDevice
        function Button_CloseDevicePushed(app, event)
            app.dr.set_mode(0, 1);

            configureCallback(app.dr.dev,"off")
            if isempty(app.Data_motor_1)
                delete(app);
            end
            if isempty(app.Patient)
                delete(app)
            end
            Save_dataset(app);
            Save_data_all(app);
            if app.IsOP == 1
                write(app.op_clent,'stop');
            end
            delete(app);            

   
        end

        % Button pushed function: Button_MaxAngle_Before
        function Button_MaxAngle_BeforePushed(app, event)
            EnableOff(app)
            % -----------标定最大旋转角度------------
            app.Label_State.Text = "康复前最大角度标记";
            app.Label_Tips.Text = (['请患者主动弯曲膝关节,达到自己的最大角度后放松',newline,'点击【结束】完成最大角度测量,']);
            app.dr.set_mode(0, 1);


            receive_data_op_clearBuff(app);

            app.Data_motor_1 = [];
            app.Data_motor_2 = [];

            
            app.dr.enable_angle_speed_torque_state(1);
            app.dr.enable_angle_speed_torque_state(2);
            app.max_degree=0;
            app.state_now_motor.degree = [0,0];
            while 1
                if mean(abs(app.state_now_motor.degree)) > app.max_degree
                    app.max_degree = mean(abs(app.state_now_motor.degree));
                end

                if app.IsStop == 1
                    app.dr.set_mode(0, 2);
                    break;
                end
                receive_data_op(app);
                pause(0.01);
            end
            app.IsStop = 0;

            app.dr.disable_angle_speed_torque_state(1);
            app.dr.disable_angle_speed_torque_state(2);
            flush(app.dr.dev);


            app.Label_Tips.Text = "最大角度测量完成";
            pause(1);
            app.Label_Tips.Text = "请点击“开始标定”进行被动训练活动范围标定";

            linkage_angle = motor2linkage(app,app.max_degree);

            app.Label_MaxDegree.Text = ["训练前最大角度:",round(linkage_angle)];

            app.Patient.pre_angle_auto = linkage_angle;
            app.EditField_StartDegreeCalib.Value = double(round(linkage_angle)) - 30;
            app.Label_State.Text = " ";

            EnableOn(app);
        end

        % Button pushed function: Button_MaxAngle_After
        function Button_MaxAngle_AfterPushed(app, event)
            EnableOff(app);
            % -----------标定最大旋转角度------------
            app.Label_Tips.Text='请医师协助托住患肢并缓慢放下';
            pause(3);
            app.dr.set_mode(0, 1);

            app.Label_State.Text = "康复后最大角度标记";
            app.Label_Tips.Text = (['请患者主动弯曲膝关节,达到自己的最大角度后放松',newline,'点击【结束】完成最大角度测量']);
            app.max_degree=0;
            
            app.Data_motor_1 = [];
            app.Data_motor_2 = [];

            app.dr.enable_angle_speed_torque_state(1);
            app.dr.enable_angle_speed_torque_state(2);
            while 1
                if mean(abs(app.state_now_motor.degree)) > app.max_degree
                    app.max_degree = mean(abs(app.state_now_motor.degree));
                end

                if app.IsStop == 1
                    app.dr.set_mode(0, 2);
                    break;
                end
                pause(0.01);
            end
            app.IsStop = 0;

            app.dr.disable_angle_speed_torque_state(1);
            app.dr.disable_angle_speed_torque_state(2);
            flush(app.dr.dev);


            app.Label_Tips.Text = "最大角度测量完成";
            pause(1);
            app.Label_Tips.Text = ['请患者脱下康复设备',newline,'医师使用量角器进行膝关节屈曲角度测量，并将结果输入对话框'];

            after_degree = inputdlg('医师使用量角器测量训练后患者膝关节最大屈曲角度','输入',[1 30],{'0'});
            app.Patient.after_angle = str2double(after_degree);
            
            linkage_angle=motor2linkage(app,app.max_degree);

            app.Patient.after_angle_auto = linkage_angle;

            app.Label_MaxDegree.Text =["训练后最大角度:",round(linkage_angle)];
            app.Label_Tips.Text='请关闭设备';
            EnableOn(app);
        end

        % Button pushed function: Button_StartCalib
        function Button_StartCalibPushed(app, event)
            EnableOff(app);
            app.dr.set_mode(0, 2);
            app.Panel_Calibration.Enable = 'on';
            app.EditField_StartDegreeCalib.Enable = 'off';
            app.Button_StartCalib.Enable = 'off';
            app.Button_DegreePlus.Enable = 'off';

            % -----------医生辅助被动运动------------
            app.Calib_times = app.Calib_times+1; %标定次数+1
            app.Calib_config{app.Calib_times} =  struct("degree_end",0, ...
                                                        "degree_start",app.EditField_StartDegreeCalib.Value, ...
                                                        "speed",0, ...
                                                        "times",3);

            % 设置开始位置
            degree_start = app.EditField_StartDegreeCalib.Value;
            degree_start = linkage2motor(app,degree_start);
            app.Label_Tips.Text = "复位中，请等待";

            app.dr.enable_angle_speed_torque_state(1); % 开启实时反馈使能
            app.dr.enable_angle_speed_torque_state(2); % 开启实时反馈使能

            % 电机回归开始位置
            app.dr.set_angles(app.id, [degree_start,-degree_start], 1, 0.5, 1);
            while abs(mean(abs(app.state_now_motor.degree)) - degree_start) > 0.5
                pause(0.1);
            end
            pause(1);
            % 提示语
            app.Label_Tips.Text = "3秒后设备将逐渐增加角度进行被动运动";
            pause(3);
            app.Button_DegreePlus.Enable = 'on';
            app.Label_State.Text = "关节活动范围标定";
            app.Label_Tips.Text = (['请医生逐次点击加号,直到达到患者膝关节三分疼痛',newline,'点击【结束】进入标定过程']);
            app.Data_motor_1 = [];
            app.Data_motor_2 = [];
            app.Data_op = [];
            receive_data_op_clearBuff(app);

            % 逐步加角度
            while 1
                if app.IsPlus == 1
                    app.IsPlus = 0;
                    app.dr.step_angles(app.id, [5,-5], 2, 0.2, 1);
                end
                if app.IsStop == 1
                    app.IsPain = 1;
                    app.IsStop = 0;
                    Degree_max = mean(abs(app.state_now_motor.degree));
                    app.dr.set_mode(0,2);
                    app.Button_DegreePlus.Enable = 'off';
                    app.Label_Tips.Text = "进入标定过程";
                    break;
                end
                [~,~,~] = receive_data_op(app);
                pause(0.1);
            end

            % 保持角度
            tic;
            while toc < 5
                [~,~,~] = receive_data_op(app);
                pause(0.1);
            end
            app.IsPain = -1;
            % 电机回归开始位置
            app.dr.set_angles(app.id, [degree_start,-degree_start], 2, 0.2, 1);
            while abs(mean(abs(app.state_now_motor.degree)) - degree_start) > 0.5
                [~,~,~] = receive_data_op(app);
                pause(0.1);                
            end
            pause(1);
            app.dr.set_mode(0,2);

% 正式标定数据
            if app.IsOP == 1
                T = 15;       
                x = 0:0.1:T+1;
                angle_go = (Degree_max-degree_start)/2 * cos(pi/T * x + pi) + (Degree_max+degree_start)/2;
                angle_back = (Degree_max-degree_start)/2 * cos(pi/T * x ) + (Degree_max+degree_start)/2;
                pause(2);
                receive_data_op_clearBuff(app);
                app.Data_motor_1 = [];
                app.Data_motor_2 = [];
                app.Data_op = [];
    
                for i = 1 : 1
                    % 屈曲
                    app.IsPain = 0;
                    tic;
                    while toc < T
                        pause(0.1);
                        t = ceil(toc*10);
                        target_degree = angle_go(t);
                        app.dr.set_angles([1,2], [target_degree,-target_degree], 1, app.f/2, 0);
                        [~,~,~] = receive_data_op(app);
                        disp(t);
                    end
                    toc;
                    % 保持
                    app.IsPain = 1;
                    tic;
                    while toc < 5
                        pause(0.1);
                        [~,~,~] = receive_data_op(app);
                    end
                    toc;
                    % 电机回归开始位置
                    app.IsPain = -1;
                    tic;
                    while toc < T
                        pause(0.1);
                        t = ceil(toc*10);
                        target_degree = angle_back(t);
                        app.dr.set_angles([1,2], [target_degree,-target_degree], 1, app.f/2, 0);
                        [~,~,~] = receive_data_op(app);
                        disp(t);
                    end
                    toc;
                    % 电机保持在开始位置
                    app.IsPain = 0;
                    tic;
                    while toc < 5
                        pause(0.1);
                        [~,~,~] = receive_data_op(app);
                    end
                    toc;
                end
            end

            % 结束标定 保存数据
            pause(1);
            app.dr.disable_angle_speed_torque_state(1);
            app.dr.disable_angle_speed_torque_state(2);
            flush(app.dr.dev);

            Save_dataset(app);
            app.Calib_data{app.Calib_times} = {app.Data_motor_1,app.Data_motor_2,app.Data_op};           
            calib_data = app.Calib_data{app.Calib_times};
            save(strcat(app.name_save_mat,'Calibration',app.Patient.name),"calib_data");
            app.Label_Tips.Text = "标定运动记录完成";
            app.Label_DegreeCalib.Text = ["三分疼痛角度:",round(motor2linkage(app,mean(Degree_max)))];
            pause(1);
            app.Label_Tips.Text = ['请设置康复训练的模式和参数',newline,'点击【开始训练】进行康复训练'];

            % 数据整理
            app.Model = TrainingModel(app,app.Data_op);

            app.state_degree_end = motor2linkage(app,mean(Degree_max));
            app.Calib_config{app.Calib_times}.degree_end = mean(Degree_max);
            app.Calib_config{app.Calib_times}.speed = max(app.Data_motor_1(:,2));
            app.Adapt_times = 0;
            app.Adapt_data = [];
            app.EditField_StartDegreeCalib.Enable = 'on';
            app.Button_StartCalib.Enable = 'on';
            app.Button_DegreePlus.Enable = 'on';
            EnableOn(app);
        end

        % Button pushed function: Button_DegreePlus
        function Button_DegreePlusPushed(app, event)
            app.IsPlus = 1;
        end

        % Value changed function: DropDown_TrainMode
        function DropDown_TrainModeValueChanged(app, event)
            value = app.DropDown_TrainMode.Value;
           
            switch value
                case '请选择'
                    app.Button_TrainStart.Enable = 'off';
                case '被动训练'
                    app.DropDown_TractionForce.Enable = 'off';
                    app.DropDown_TractionTime.Enable = 'off';
                    app.DropDown_AgainstPower.Enable = 'off';
                    app.DropDown_RestTime.Enable = 'on';
                    app.DropDown_Speed.Enable = 'on';
                    app.DropDown_KeepTime.Enable = 'on';
                    app.DropDown_Times.Enable = 'on';
                    app.EditField_StartDegree.Enable = 'on';
                    app.EditField_EndDegree.Enable = 'on';
                    app.Button_TrainStart.Enable = 'on';
                    app.Label_Tips.Text = "确认好训练参数后点击‘开始训练’进行训练";
                case '对抗训练'
                    app.DropDown_TractionForce.Enable = 'off';
                    app.DropDown_TractionTime.Enable = 'off';
                    app.DropDown_RestTime.Enable = 'on';
                    app.DropDown_Speed.Enable = 'on';
                    app.DropDown_KeepTime.Enable = 'on';
                    app.DropDown_Times.Enable = 'on';
                    app.EditField_StartDegree.Enable = 'on';
                    app.DropDown_AgainstPower.Enable = 'on';
                    app.EditField_EndDegree.Enable = 'on';
                    app.Button_TrainStart.Enable = 'on';
                    app.Label_Tips.Text = "确认好训练参数后点击‘开始训练’进行训练";
                case '自适应被动训练'
                    app.DropDown_TractionForce.Enable = 'off';
                    app.DropDown_TractionTime.Enable = 'off';
                    app.DropDown_AgainstPower.Enable = 'off';
                    app.EditField_EndDegree.Enable = 'off';
                    app.DropDown_RestTime.Enable = 'on';
                    app.DropDown_Speed.Enable = 'on';
                    app.DropDown_KeepTime.Enable = 'on';
                    app.DropDown_Times.Enable = 'on';
                    app.EditField_StartDegree.Enable = 'on';
                    if app.IsOP == 0
                        app.Button_TrainStart.Enable = 'off';
                        app.Label_Tips.Text = "未使用肌电仪，无法进行该训练";
                    else
                        app.Button_TrainStart.Enable = 'on';
                        app.Label_Tips.Text = "确认好训练参数后点击‘开始训练’进行训练";
                    end
                case '自适应对抗训练'
                    app.DropDown_TractionForce.Enable = 'off';
                    app.DropDown_TractionTime.Enable = 'off';
                    app.DropDown_RestTime.Enable = 'on';
                    app.DropDown_AgainstPower.Enable = 'on';
                    app.DropDown_Speed.Enable = 'on';
                    app.DropDown_KeepTime.Enable = 'on';
                    app.DropDown_Times.Enable = 'on';
                    app.EditField_StartDegree.Enable = 'on';
                    app.EditField_EndDegree.Enable = 'off';
                    if app.IsOP == 0
                        app.Button_TrainStart.Enable = 'off';
                        app.Label_Tips.Text = "未使用肌电仪，无法进行该训练";
                    else
                        app.Button_TrainStart.Enable = 'on';
                        app.Label_Tips.Text = "确认好训练参数后点击‘开始训练’进行训练";
                    end
                case '静态牵引'
                    app.Button_TrainStart.Enable='on';
                    app.DropDown_TractionForce.Enable = 'on';
                    app.DropDown_TractionTime.Enable = 'on';
                    app.DropDown_RestTime.Enable = 'off';
                    app.DropDown_AgainstPower.Enable = 'off';
                    app.DropDown_Speed.Enable = 'off';
                    app.DropDown_KeepTime.Enable = 'off';
                    app.DropDown_Times.Enable = 'off';
                    app.EditField_StartDegree.Enable = 'off';
                    app.EditField_EndDegree.Enable = 'on';
                    app.Label_Tips.Text = "确认好训练参数后点击‘开始训练’进行训练";
                case '自适应充气'
                    app.Button_TrainStart.Enable='on';
                    app.DropDown_RestTime.Enable = 'off';
                    app.DropDown_AgainstPower.Enable = 'off';
                    app.DropDown_Speed.Enable = 'off';
                    app.DropDown_KeepTime.Enable = 'off';
                    app.DropDown_Times.Enable = 'off';
                    app.EditField_StartDegree.Enable = 'off';
                    app.EditField_EndDegree.Enable = 'off';
                    app.DropDown_TractionForce.Enable = 'off';
                    app.DropDown_TractionTime.Enable = 'off';
                    app.Label_Tips.Text = "确认好训练参数后点击‘开始训练’进行训练";
            end
        end

        % Button pushed function: Button_TrainStart
        function Button_TrainStartPushed(app, event)
            EnableOff(app);
            app.dr.set_mode(0, 2);
            app.Train_times = app.Train_times+1; %训练次数+1

            
            degree_start = app.EditField_StartDegree.Value;
            degree_start = linkage2motor(app,degree_start);
            % 回归初始位置
            app.dr.set_angles(app.id, [degree_start,-degree_start], 2, 1, 1);
            app.dr.positions_done(app.id);
            % 清空数据缓存
            flush(app.dr.dev);
            app.IsStop = 0;
            app.IsPain = 0;
            app.Data_motor_1 = [];
            app.Data_motor_2 = [];
            app.Data_op = [];
            app.keep_total_save = [];
            app.Label_State.Text = " "; % 清空提示
            app.Lamp.Color = [0 1 0];
            app.Label_Tips.Text = "开始训练";
            switch app.DropDown_TrainMode.Value
                case "被动训练"
                    train_mode_1(app);
                    app.Train_data{app.Train_times} = {app.Data_motor_1,app.Data_motor_2,app.Data_op};
                case "对抗训练"
                    train_mode_2(app);
                    app.Train_data{app.Train_times} = {app.Data_motor_1,app.Data_motor_2,app.Data_op};
                case "自适应被动训练"
                    fuzzy_control(app);
                    train_mode_3(app);
                    app.Train_data{app.Train_times} = {app.Data_motor_1,app.Data_motor_2,app.Data_op,app.keep_total_save};
                case "自适应对抗训练"
                    fuzzy_control(app);
                    train_mode_4(app);
                    app.Train_data{app.Train_times} = {app.Data_motor_1,app.Data_motor_2,app.Data_op,app.keep_total_save};
                case "静态牵引"
                    train_mode_5(app);
                    % app.Train_data{app.Train_times} = {app.Data_motor_1,app.Data_motor_2,app.Data_op,app.keep_total_save};
                case "自适应充气"
                    train_mode_6(app);
                    % app.Train_data{app.Train_times} = {app.Data_motor_1,app.Data_motor_2,app.Data_op,app.keep_total_save};
                case "请选择"
                    app.Label_Tips.Text = "未选择训练模式，请重试";
                    return;
            end
            app.Lamp.Color = [0.8 0.8 0.8];
% 保存单组训练数据
            Save_dataset(app)
            Save_data_part(app);
            app.Label_Tips.Text = ['单次训练结束，确认参数后点击【开始训练】可再次训练',newline,'训练完毕请进行拉伸训练，或点击【训练后屈曲角度检测】退出'];

            EnableOn(app);
        end

        % Callback function
        function Button_StretchTozeroPushed(app, event)
            EnableOff(app);

            write(app.Com_stretch,'7',"char"); % 推杆归零
            back = read(app.Com_stretch,1,"char");

            pause(3);
            EnableOn(app);
        end

        % Callback function
        function Button_StretchMinusPushed(app, event)
            write(app.Com_stretch,'4',"char"); % 收缩指令为发送4
            back = read(app.Com_stretch,1,"char");
        end

        % Callback function
        function Button_StretchOncePushed(app, event)
            EnableOff(app);

            % 调整拉伸位置
            app.Label_Tips.Text=['请医师协助托住患肢并缓慢放下，调整至自然放松的位置',newline,'按【结束】键开始拉伸'];
            pause(3);
            app.order_send = app.funcMotor.order_close();
            transceive(app,app.order_send); 
            while 1
                app.order_send = app.funcMotor.order_state();
                transceive(app,app.order_send);
                if app.IsStop == 1
                    break;
                end
                PlotData_motor(app);
                pause(0.001);
            end
            app.IsStop = 0;  
            pause(1)

            % 开始拉伸控制
            % 清空缓存区
            % app.Label_Tips.Text = [sprintf('\n %d',app.Com_stretch.NumBytesAvailable)]; %串口中剩余未发送信息数量
            % pause(2);

            if (app.Com_stretch.NumBytesAvailable>0)
                flush(app.Com_stretch);
            end
           % 发送控制指令,并确认
              app.Label_Tips.Text="即将开始拉伸";
              pause(1);
              app.Label_Tips.Text="拉伸进行中";
              write(app.Com_stretch,'2',"char"); %%%ardiuno
              back=read(app.Com_stretch,1,"char");
            
            pause(10);%%%%%%%%%%%
            app.Label_Tips.Text=['点击【推杆归零】回归零位，或使用【+】【-】自由调整',newline,'结束训练请将推杆归零后点击【训练后屈曲角度检测】'];
            
            EnableOn(app);
        end

        % Callback function
        function Button_StretchPlusPushed(app, event)
            write(app.Com_stretch,'3',"char"); % 拉伸指令为发送3
            back = read(app.Com_stretch,1,"char");
        end

        % Button pushed function: Button_Finish
        function Button_FinishPushed(app, event)
            app.IsStop = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
            if isempty(app.Data_motor_1)
                delete(app);
                return;
            end
            if isempty(app.Patient)
                delete(app);
                return;
            end
            Save_dataset(app);
            Save_data_all(app);
            if app.IsOP == 1
                write(app.op_clent,'stop');
            end
            delete(app);
        end

        % Button pushed function: Button_stretch_2
        function Button_stretch_2Pushed(app, event)
            write(app.Com_stretch,'o',"char"); % 开启反馈

            app.Stretch_State.Text='反馈开启';
            
            %% 准备调整
            app.Stretch_State.Text = sprintf('准备中');
            tic
            while (toc<5 && app.stopflag~="s t o p")
                % read_Arduino(app);
                % Plot_Stretch(app);
                % Plot_Sensor(app);
                pause(0.01);
            end

           %% 大腿自适应充气
           app.Stretch_State.Text = sprintf('大腿充气');
           Thigh_limit=[9 9 5]; %大腿充气上限
           write(app.Com_stretch,'5',"char"); % 大腿充气
           tic
            while(app.stopflag~="s t o p" && toc<10 && app.inflation_flag==0)
                inflation_time=toc;
                app.Stretch_State.Text = sprintf('充气时间：%f ',inflation_time);

                %% 大腿充气判断
                cur_data_press=app.data_press(end-30:end,:); %传感器当前30点数据
                cur_press_vec=mean(cur_data_press); %平均后转换为向量
                Thigh_press=cur_press_vec(1:3); %得到大腿当前压力

                if sum(Thigh_press>=Thigh_limit)==3||sum(Thigh_press>app.Press_limit(1:3))>=1
                    Mark_press=Thigh_press;
                    break;
                end
            end
            write(app.Com_stretch,'6',"char"); % 大腿保持
            app.Stretch_State.Text = sprintf('大腿充气结束');
            tic
            while toc<1
                pause(0.01);
            end

            %% 小腿自适应充气
            app.Stretch_State.Text = sprintf('小腿充气');
            calf_limit=[5 5 5 5]; %小腿充气上限
            write(app.Com_stretch,'8',"char"); % 小腿充气
            tic
            while(app.stopflag~="s t o p" && toc<10 && app.inflation_flag==0)
                inflation_time=toc;
                app.Stretch_State.Text = sprintf('充气时间：%f ',inflation_time);

                %% 小腿充气判断
                cur_data_press=app.data_press(end-30:end,:); %传感器当前30点数据
                cur_press_vec=mean(cur_data_press); %平均后转换为向量
                calf_press=cur_press_vec(5:8); %得到小腿前侧当前压力

                if sum(calf_press>=calf_limit)>=3||sum(calf_press>app.Press_limit(5:8))>=1
                    Mark_press=[Mark_press,calf_press];
                    break;
                end
            end
            

            write(app.Com_stretch,'9',"char"); % 小腿保持
            app.Stretch_State.Text = sprintf('小腿充气结束');
            
            %% 充气完毕结束反馈
            write(app.Com_stretch,'c',"char"); % 结束反馈
            app.Stretch_State.Text=sprintf('充气完毕：用时 %f ',inflation_time);
            pause(0.5);
            app.stopflag=''; %重置标志位
            if (app.Com_stretch.NumBytesAvailable>0) %清空串口
                flush(app.Com_stretch);
            end

            % Save_dataset(app);  %按当前时间保存数据
            % save_buff=app.data_pos;
            % save_buff2=app.data_press;
            % save(app.name_save,"save_buff","save_buff2");
        end

        % Button pushed function: Button_stretch_3
        function Button_stretch_3Pushed(app, event)
             app.IsStop = 0;
            % 设置拉伸参数
           % train_mode = app.DropDown_TrainMode.Value;
            traction_force = app.DropDown_TractionForce.Value;
            traction_time = app.DropDown_TractionTime.Value;
            %app.Train_config{app.Train_times} = struct("train_mode",train_mode, ...
                                                     %  "traction_force",traction_force, ...
                                                   %    "traction_time",traction_time); 
            set_load = str2double(app.DropDown_TractionForce.Value);
            traction_keeptime = str2double(app.DropDown_TractionTime.Value);
            real_load=0;  %当前牵引力
            times_flag=0; %增加牵引行程的次数

            windowSize = 5;  %设置滤波参数
            b = (1/windowSize)*ones(1,windowSize);
            a=1;
            % 开启反馈静止10s
            write(app.Com_stretch,'o',"char"); % 开启反馈

            tic
            while (toc<10 && app.IsStop ==0)
                pause(0.01);
            end

            % 读取推杆初始状态
            initial_data=app.data_pos(end-49:end,:);
            initial_data_fit=filter(b,a,initial_data); %分别对8列初始数据均值滤波
            ini_force_vec=mean(initial_data_fit(:,5:8));%一行四个向量

            % 每6s检测一次力状态，未达阈值则增加行程
            while (real_load<set_load && times_flag < 3 &&  app.IsStop == 0)
                write(app.Com_stretch,'3',"char"); % 加
                write(app.Com_stretch,'3',"char"); % 加
                write(app.Com_stretch,'3',"char"); % 加
                times_flag=times_flag+1;                
                tic
                while (toc<6 && app.IsStop == 0)
                    pause(0.01);
                end

                % 每次循环中计算是否达到要求状态
                current_data=app.data_pos(end-49:end,:);
                current_data_fit=filter(b,a,current_data);
                cur_force_vec=mean(current_data_fit(:,5:8));% 结果为一行四个向量

                real_load=sum(cur_force_vec-ini_force_vec);

                % linshi=[times_flag,ini_force_vec, cur_force_vec,real_load];
                % disp(linshi);
            end
            
            % 达到设定力或最大行程保持10s
            tic
            while (toc<traction_keeptime &&  app.IsStop == 0)
                pause(0.01);
            end
            current_data=app.data_pos(end-49:end,:);
            current_data_fit=filter(b,a,current_data);
            app.actuators_max_movment=mean(current_data_fit(:,1:4));

            % 推杆归零
            write(app.Com_stretch,'1',"char"); % 归零
            tic
            while (toc<5 &&  app.IsStop == 0)
                pause(0.01);
            end

            % 结束Arduino读取并重置标志位
            write(app.Com_stretch,'c',"char"); % 结束反馈
            pause(0.5);
            app.stopflag=''; %重置标志位
            if (app.Com_stretch.NumBytesAvailable>0) %清空串口
                flush(app.Com_stretch);
            end
        end

        % Button pushed function: Button_stretch
        function Button_stretchPushed(app, event)
            write(app.Com_stretch,'1',"char"); % 推杆归零
            % back=read(app.Com_stretch,1,"char");
        end

        % Button pushed function: Button_StretchPlus_2
        function Button_StretchPlus_2Pushed(app, event)
            write(app.Com_stretch,'3',"char"); % 加
            % back=read(app.Com_stretch,1,"char");
        end

        % Button pushed function: Button_StretchMinus_2
        function Button_StretchMinus_2Pushed(app, event)
            write(app.Com_stretch,'4',"char"); % 减
            % back=read(app.Com_stretch,1,"char");
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "充气"
                    write(app.Com_stretch,'5',"char"); % 大腿充气
                case "保持"
                    write(app.Com_stretch,'6',"char"); % 大腿保持
                case "放气"
                    write(app.Com_stretch,'7',"char"); % 大腿放气
                otherwise
                    %     msgbox("功能无效，请重新选择","Notice","error");
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case "充气"
                    write(app.Com_stretch,'8',"char"); % 小腿充气
                case "保持"
                    write(app.Com_stretch,'9',"char"); % 小腿保持
                case "放气"
                    write(app.Com_stretch,'q',"char"); % 小腿放气
                otherwise
                    %     msgbox("功能无效，请重新选择","Notice","error");
            end
        end

        % Button pushed function: Button_stretch_4
        function Button_stretch_4Pushed(app, event)
            app.IsStop=0;
            if (app.Com_stretch.NumBytesAvailable>0) %清空串口
                flush(app.Com_stretch);
            end
            %% 开启反馈
            write(app.Com_stretch,'o',"char"); % 开启反馈

            %tic
            %while (toc<10 && app.IsStop==0)
            while(app.IsStop==0)
                pause(0.01);
                disp(app.Com_stretch.NumBytesAvailable);
            end          
            
            %% 结束Arduino读取并重置标志位
            write(app.Com_stretch,'c',"char"); % 结束反馈
            pause(0.5);
            if (app.Com_stretch.NumBytesAvailable>0) %清空串口
                flush(app.Com_stretch);
            end

            app.IsStop=0;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_TwoMotor = uitab(app.TabGroup);
            app.Tab_TwoMotor.Title = '设备控制';
            app.Panel_TrainSet = uipanel(app.Tab_TwoMotor);
            app.Panel_TrainSet.Title = '康复训练';
            app.Label = uilabel(app.Panel_TrainSet);
            app.Label.Text = '次数';
            app.Label_2 = uilabel(app.Panel_TrainSet);
            app.Label_2.Text = '起始角度';
            app.EditField_StartDegree = uieditfield(app.Panel_TrainSet, 'numeric');
            app.EditField_StartDegree.Limits = [5 85];
            app.EditField_StartDegree.Value = 30;
            app.Label_3 = uilabel(app.Panel_TrainSet);
            app.Label_3.Text = '终止角度';
            app.EditField_EndDegree = uieditfield(app.Panel_TrainSet, 'numeric');
            app.EditField_EndDegree.Limits = [5 85];
            app.EditField_EndDegree.Value = 60;
            app.Button_TrainStart = uibutton(app.Panel_TrainSet, 'push');
            app.Button_TrainStart.ButtonPushedFcn = createCallbackFcn(app, @Button_TrainStartPushed, true);
            app.Button_TrainStart.Enable = 'off';
            app.Button_TrainStart.Text = '开始训练';
            app.DropDown_Times = uidropdown(app.Panel_TrainSet);
            app.DropDown_Times.Items = {'1', '2', '3', '5', '10', '15', '20', '25', '30'};
            app.DropDown_Times.Value = '1';
            app.Label_11 = uilabel(app.Panel_TrainSet);
            app.Label_11.Text = '速度';
            app.DropDown_Speed = uidropdown(app.Panel_TrainSet);
            app.DropDown_Speed.Items = {'缓慢', '适中', '快速'};
            app.DropDown_Speed.Value = '适中';
            app.Label_16 = uilabel(app.Panel_TrainSet);
            app.Label_16.Text = '训练模式';
            app.DropDown_TrainMode = uidropdown(app.Panel_TrainSet);
            app.DropDown_TrainMode.Items = {'请选择', '被动训练', '对抗训练', '自适应被动训练', '自适应对抗训练', '静态牵引', '自适应充气'};
            app.DropDown_TrainMode.ValueChangedFcn = createCallbackFcn(app, @DropDown_TrainModeValueChanged, true);
            app.DropDown_TrainMode.Value = '请选择';
            app.Label_17 = uilabel(app.Panel_TrainSet);
            app.Label_17.Text = {'对抗'; '力量'};
            app.DropDown_AgainstPower = uidropdown(app.Panel_TrainSet);
            app.DropDown_AgainstPower.Items = {'较小', '适中', '较大'};
            app.DropDown_AgainstPower.Value = '适中';
            app.Label_19 = uilabel(app.Panel_TrainSet);
            app.Label_19.Text = {'保持/对抗'; '时间(秒)'};
            app.DropDown_KeepTime = uidropdown(app.Panel_TrainSet);
            app.DropDown_KeepTime.Items = {'5', '10', '15'};
            app.DropDown_KeepTime.Value = '10';
            app.Label_20 = uilabel(app.Panel_TrainSet);
            app.Label_20.Text = '牵引力';
            app.DropDown_TractionForce = uidropdown(app.Panel_TrainSet);
            app.DropDown_TractionForce.Items = {'10', '15', '20', '25', '30', '35'};
            app.DropDown_TractionForce.Value = '10';
            app.Label_21 = uilabel(app.Panel_TrainSet);
            app.Label_21.Text = '拉伸时间(秒)';
            app.DropDown_TractionTime = uidropdown(app.Panel_TrainSet);
            app.DropDown_TractionTime.Items = {'1', '5', '10', '15'};
            app.DropDown_TractionTime.Value = '1';
            app.Label_22 = uilabel(app.Panel_TrainSet);
            app.Label_22.Text = {'休息时'; '间(秒)'};
            app.DropDown_RestTime = uidropdown(app.Panel_TrainSet);
            app.DropDown_RestTime.Items = {'5', '10', '15'};
            app.DropDown_RestTime.Value = '10';
            app.Panel_Calibration = uipanel(app.Tab_TwoMotor);
            app.Panel_Calibration.Title = '标定';
            app.Button_StartCalib = uibutton(app.Panel_Calibration, 'push');
            app.Button_StartCalib.ButtonPushedFcn = createCallbackFcn(app, @Button_StartCalibPushed, true);
            app.Button_StartCalib.Text = '开始标定';
            app.Label_14 = uilabel(app.Panel_Calibration);
            app.Label_14.Text = '起始角度';
            app.EditField_StartDegreeCalib = uieditfield(app.Panel_Calibration, 'numeric');
            app.EditField_StartDegreeCalib.Limits = [5 85];
            app.EditField_StartDegreeCalib.Value = 30;
            app.Button_DegreePlus = uibutton(app.Panel_Calibration, 'push');
            app.Button_DegreePlus.ButtonPushedFcn = createCallbackFcn(app, @Button_DegreePlusPushed, true);
            app.Button_DegreePlus.Enable = 'off';
            app.Button_DegreePlus.Text = '+';
            app.Label_DegreePlus = uilabel(app.Panel_Calibration);
            app.Label_DegreePlus.Enable = 'off';
            app.Label_DegreePlus.Text = '增加角度按钮';
            app.Label_DegreeCalib = uilabel(app.Panel_Calibration);
            app.Label_DegreeCalib.Text = '标 定 角 度';
            app.Panel_RangeDetect = uipanel(app.Tab_TwoMotor);
            app.Panel_RangeDetect.Title = '运动范围检测';
            app.Button_MaxAngle_Before = uibutton(app.Panel_RangeDetect, 'push');
            app.Button_MaxAngle_Before.ButtonPushedFcn = createCallbackFcn(app, @Button_MaxAngle_BeforePushed, true);
            app.Button_MaxAngle_Before.Text = '训练前屈曲角度检测';
            app.Button_MaxAngle_After = uibutton(app.Panel_RangeDetect, 'push');
            app.Button_MaxAngle_After.ButtonPushedFcn = createCallbackFcn(app, @Button_MaxAngle_AfterPushed, true);
            app.Button_MaxAngle_After.Text = '训练后屈曲角度检测';
            app.Label_MaxDegree = uilabel(app.Panel_RangeDetect);
            app.Label_MaxDegree.Text = '最 大 角 度';
            app.Panel_DeviceOpra = uipanel(app.Tab_TwoMotor);
            app.Panel_DeviceOpra.Title = '设备操作';
            app.Button_CloseDevice = uibutton(app.Panel_DeviceOpra, 'push');
            app.Button_CloseDevice.ButtonPushedFcn = createCallbackFcn(app, @Button_CloseDevicePushed, true);
            app.Button_CloseDevice.Text = '关闭设备';
            app.Button_InitAll = uibutton(app.Panel_DeviceOpra, 'push');
            app.Button_InitAll.ButtonPushedFcn = createCallbackFcn(app, @Button_InitAllPushed, true);
            app.Button_InitAll.Text = '启动设备';
            app.Button_Finish = uibutton(app.Panel_DeviceOpra, 'push');
            app.Button_Finish.ButtonPushedFcn = createCallbackFcn(app, @Button_FinishPushed, true);
            app.Button_Finish.Text = '结束';
            app.Tab_Opensignal = uitab(app.TabGroup);
            app.Tab_Opensignal.Title = 'Opensignal';
            app.ButtonGroup_OP_OPRA = uibuttongroup(app.Tab_Opensignal);
            app.ButtonGroup_OP_OPRA.Title = '操作';
            app.SelectButton_disable = uiradiobutton(app.ButtonGroup_OP_OPRA);
            app.SelectButton_disable.Text = 'disable';
            app.SelectButton_enable = uiradiobutton(app.ButtonGroup_OP_OPRA);
            app.SelectButton_enable.Text = 'enable';
            app.SelectButton_config = uiradiobutton(app.ButtonGroup_OP_OPRA);
            app.SelectButton_config.Text = 'config';
            app.SelectButton_devices = uiradiobutton(app.ButtonGroup_OP_OPRA);
            app.SelectButton_devices.Text = 'devices';
            app.SelectButton_devices.Value = true;
            app.Button_send = uibutton(app.ButtonGroup_OP_OPRA, 'push');
            app.Button_send.Text = '确定';
            app.Button_update_config = uibutton(app.ButtonGroup_OP_OPRA, 'push');
            app.Button_update_config.Text = '更新参数';
            app.Panel_OP_Message = uipanel(app.Tab_Opensignal);
            app.Panel_OP_Message.Title = '消息';
            app.Label_config = uilabel(app.Panel_OP_Message);
            app.Label_config.Text = '状态';
            app.Label_send = uilabel(app.Panel_OP_Message);
            app.Label_send.Text = '发送';
            app.Label_receive = uilabel(app.Panel_OP_Message);
            app.Label_receive.Text = '接收';
            app.TextArea_config = uitextarea(app.Panel_OP_Message);
            app.TextArea_config.Editable = 'off';
            app.TextArea_send = uitextarea(app.Panel_OP_Message);
            app.TextArea_send.Editable = 'off';
            app.TextArea_receive = uitextarea(app.Panel_OP_Message);
            app.TextArea_receive.Editable = 'off';
            app.Button_InitOP = uibutton(app.Tab_Opensignal, 'push');
            app.Button_InitOP.Text = '初始化';
            app.Label_StateOP = uilabel(app.Tab_Opensignal);
            app.Label_OP_ID = uilabel(app.Tab_Opensignal);
            app.Label_OP_ID.Text = 'Label2';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '推杆控制';
            app.ButtonGroup = uibuttongroup(app.Tab_3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '大腿气囊控制';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = '充气';
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '保持';
            app.Button_2.Value = true;
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '放气';
            app.ButtonGroup_2 = uibuttongroup(app.Tab_3);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '小腿气囊控制';
            app.Button_4 = uitogglebutton(app.ButtonGroup_2);
            app.Button_4.Text = '充气';
            app.Button_5 = uitogglebutton(app.ButtonGroup_2);
            app.Button_5.Text = '保持';
            app.Button_5.Value = true;
            app.Button_6 = uitogglebutton(app.ButtonGroup_2);
            app.Button_6.Text = '放气';
            app.Button_StretchPlus_2 = uibutton(app.Tab_3, 'push');
            app.Button_StretchPlus_2.ButtonPushedFcn = createCallbackFcn(app, @Button_StretchPlus_2Pushed, true);
            app.Button_StretchPlus_2.Text = '+';
            app.Button_StretchMinus_2 = uibutton(app.Tab_3, 'push');
            app.Button_StretchMinus_2.ButtonPushedFcn = createCallbackFcn(app, @Button_StretchMinus_2Pushed, true);
            app.Button_StretchMinus_2.Text = '-';
            app.Button_stretch = uibutton(app.Tab_3, 'push');
            app.Button_stretch.ButtonPushedFcn = createCallbackFcn(app, @Button_stretchPushed, true);
            app.Button_stretch.Text = '推杆归零';
            app.Button_stretch_2 = uibutton(app.Tab_3, 'push');
            app.Button_stretch_2.ButtonPushedFcn = createCallbackFcn(app, @Button_stretch_2Pushed, true);
            app.Button_stretch_2.Text = '自适应充气';
            app.Button_stretch_3 = uibutton(app.Tab_3, 'push');
            app.Button_stretch_3.ButtonPushedFcn = createCallbackFcn(app, @Button_stretch_3Pushed, true);
            app.Button_stretch_3.Text = '静态牵引';
            app.Button_stretch_4 = uibutton(app.Tab_3, 'push');
            app.Button_stretch_4.ButtonPushedFcn = createCallbackFcn(app, @Button_stretch_4Pushed, true);
            app.Button_stretch_4.Text = '开启反馈';
            app.Panel_MotorUIAxes = uipanel(app.UIFigure);
            app.Panel_MotorUIAxes.Title = '电机数据';
            app.UIAxes_Current = uiaxes(app.Panel_MotorUIAxes);
            title(app.UIAxes_Current, '力矩')
            xlabel(app.UIAxes_Current, 'X')
            ylabel(app.UIAxes_Current, 'N·m')
            zlabel(app.UIAxes_Current, 'Z')
            app.UIAxes_Current.XLim = [0 500];
            app.UIAxes_Current.YLim = [-20 20];
            app.UIAxes_Current.XDir = 'reverse';
            app.UIAxes_Current.XTick = [0 100 200 300 400 500];
            app.UIAxes_Current.XTickLabel = {'0'; '2s'; '4s'; '6s'; '8s'; '10s'};
            app.UIAxes_Current.YTick = [-15 -10 -5 0 5 10 15];
            app.UIAxes_Current.YTickLabel = {'-15'; '-10'; '-5'; '0'; '5'; '10'; '15'};
            app.UIAxes_Degree = uiaxes(app.Panel_MotorUIAxes);
            title(app.UIAxes_Degree, '电机角度')
            xlabel(app.UIAxes_Degree, 'X')
            ylabel(app.UIAxes_Degree, '度°')
            zlabel(app.UIAxes_Degree, 'Z')
            app.UIAxes_Degree.XLim = [0 500];
            app.UIAxes_Degree.YLim = [-10 160];
            app.UIAxes_Degree.XDir = 'reverse';
            app.UIAxes_Degree.XTick = [0 100 200 300 400 500];
            app.UIAxes_Degree.XTickLabel = {'0'; '2s'; '4s'; '6s'; '8s'; '10s'};
            app.UIAxes_Degree.YTick = [0 20 40 60 80 100 120 140];
            app.UIAxes_Degree.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'};
            app.UIAxes_Speed = uiaxes(app.Panel_MotorUIAxes);
            title(app.UIAxes_Speed, '速度')
            xlabel(app.UIAxes_Speed, 'X')
            ylabel(app.UIAxes_Speed, 'r/min')
            zlabel(app.UIAxes_Speed, 'Z')
            app.UIAxes_Speed.XLim = [0 500];
            app.UIAxes_Speed.YLim = [-5 5];
            app.UIAxes_Speed.XDir = 'reverse';
            app.UIAxes_Speed.XTick = [0 100 200 300 400 500];
            app.UIAxes_Speed.XTickLabel = {'0'; '2s'; '4s'; '6s'; '8s'; '10s'};
            app.UIAxes_Speed.YTick = [-5 -2.5 0 2.5 5];
            app.UIAxes_Speed.YTickLabel = {'-5'; '-2.5'; '0'; '2.5'; '5'};
            app.Label_MotorDegree = uilabel(app.Panel_MotorUIAxes);
            app.Label_MotorDegree.Text = '关 节 角 度';
            app.Label_Tips = uilabel(app.UIFigure);
            app.Label_Tips.Text = '';
            app.Button_MsgPatient = uibutton(app.UIFigure, 'push');
            app.Button_MsgPatient.ButtonPushedFcn = createCallbackFcn(app, @Button_MsgPatientPushed, true);
            app.Button_MsgPatient.Text = '患者信息登记';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '同步';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_UpdateData = uilabel(app.UIFigure);
            app.Label_UpdateData.Text = '';
            app.Label_State = uilabel(app.UIFigure);
            app.Label_State.Text = ' ';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = '肌电数据';
            app.UIAxes_EMG_1 = uiaxes(app.Tab);
            title(app.UIAxes_EMG_1, 'CUSTOM')
            xlabel(app.UIAxes_EMG_1, 'X')
            ylabel(app.UIAxes_EMG_1, 'mV')
            zlabel(app.UIAxes_EMG_1, 'Z')
            app.UIAxes_EMG_1.XLim = [0 5000];
            app.UIAxes_EMG_1.YLim = [-3 3];
            app.UIAxes_EMG_1.XDir = 'reverse';
            app.UIAxes_EMG_1.XTick = [0 1000 2000 3000 4000 5000];
            app.UIAxes_EMG_1.XTickLabel = {'0s'; '0.5s'; '1s'; '1.5s'; '2s'; '2.5s'};
            app.UIAxes_EMG_1.YTick = [-4 -3 -2 -1 0 1 2 3 4];
            app.UIAxes_EMG_1.YTickLabel = {'-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'};
            app.UIAxes_EMG_2 = uiaxes(app.Tab);
            title(app.UIAxes_EMG_2, 'CUSTOM')
            xlabel(app.UIAxes_EMG_2, 'X')
            ylabel(app.UIAxes_EMG_2, 'mV')
            zlabel(app.UIAxes_EMG_2, 'Z')
            app.UIAxes_EMG_2.XLim = [0 5000];
            app.UIAxes_EMG_2.YLim = [-3 3];
            app.UIAxes_EMG_2.XDir = 'reverse';
            app.UIAxes_EMG_2.XTick = [0 1000 2000 3000 4000 5000];
            app.UIAxes_EMG_2.XTickLabel = {'0s'; '0.5s'; '1s'; '1.5s'; '2s'; '2.5s'};
            app.UIAxes_EMG_2.YTick = [-4 -3 -2 -1 0 1 2 3 4];
            app.UIAxes_EMG_2.YTickLabel = {'-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'};
            app.UIAxes_EMG_3 = uiaxes(app.Tab);
            title(app.UIAxes_EMG_3, 'CUSTOM')
            xlabel(app.UIAxes_EMG_3, 'X')
            ylabel(app.UIAxes_EMG_3, 'mV')
            zlabel(app.UIAxes_EMG_3, 'Z')
            app.UIAxes_EMG_3.XLim = [0 5000];
            app.UIAxes_EMG_3.YLim = [-3 3];
            app.UIAxes_EMG_3.XDir = 'reverse';
            app.UIAxes_EMG_3.XTick = [0 1000 2000 3000 4000 5000];
            app.UIAxes_EMG_3.XTickLabel = {'0s'; '0.5s'; '1s'; '1.5s'; '2s'; '2.5s'};
            app.UIAxes_EMG_3.YTick = [-4 -3 -2 -1 0 1 2 3 4];
            app.UIAxes_EMG_3.YTickLabel = {'-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'};
            app.UIAxes_EMG_4 = uiaxes(app.Tab);
            title(app.UIAxes_EMG_4, 'CUSTOM')
            xlabel(app.UIAxes_EMG_4, 'X')
            ylabel(app.UIAxes_EMG_4, 'mV')
            zlabel(app.UIAxes_EMG_4, 'Z')
            app.UIAxes_EMG_4.XLim = [0 5000];
            app.UIAxes_EMG_4.YLim = [-3 3];
            app.UIAxes_EMG_4.XDir = 'reverse';
            app.UIAxes_EMG_4.XTick = [0 1000 2000 3000 4000 5000];
            app.UIAxes_EMG_4.XTickLabel = {'0s'; '0.5s'; '1s'; '1.5s'; '2s'; '2.5s'};
            app.UIAxes_EMG_4.YTick = [-4 -3 -2 -1 0 1 2 3 4];
            app.UIAxes_EMG_4.YTickLabel = {'-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'};
            app.UIAxes_EMG_trigger = uiaxes(app.Tab);
            title(app.UIAxes_EMG_trigger, 'Trigger')
            xlabel(app.UIAxes_EMG_trigger, 'X')
            ylabel(app.UIAxes_EMG_trigger, 'mV')
            zlabel(app.UIAxes_EMG_trigger, 'Z')
            app.UIAxes_EMG_trigger.XLim = [0 5000];
            app.UIAxes_EMG_trigger.YLim = [-0.1 1.1];
            app.UIAxes_EMG_trigger.XDir = 'reverse';
            app.UIAxes_EMG_trigger.XTick = [0 1000 2000 3000 4000 5000];
            app.UIAxes_EMG_trigger.XTickLabel = {'0s'; '0.5s'; '1s'; '1.5s'; '2s'; '2.5s'};
            app.UIAxes_EMG_trigger.YTick = [-4 -3 -2 -1 0 1 2 3 4];
            app.UIAxes_EMG_trigger.YTickLabel = {'-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'};
            app.UIAxes_EMG_5 = uiaxes(app.Tab);
            title(app.UIAxes_EMG_5, 'STATE')
            xlabel(app.UIAxes_EMG_5, 'X')
            zlabel(app.UIAxes_EMG_5, 'Z')
            app.UIAxes_EMG_5.XLim = [0 5000];
            app.UIAxes_EMG_5.YLim = [-1.2 1.2];
            app.UIAxes_EMG_5.XDir = 'reverse';
            app.UIAxes_EMG_5.XTick = [0 1000 2000 3000 4000 5000];
            app.UIAxes_EMG_5.XTickLabel = {'0s'; '0.5s'; '1s'; '1.5s'; '2s'; '2.5s'};
            app.UIAxes_EMG_5.YTick = [-1 0 1];
            app.UIAxes_EMG_5.YTickLabel = {'-1'; '0'; '1'};
            app.svmGaugeLabel = uilabel(app.Tab);
            app.svmGaugeLabel.Text = 'svm输出';
            app.Gauge_SVM = uigauge(app.Tab, 'linear');
            app.Gauge_SVM.Limits = [-10 10];
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '推杆数据';
            app.Stretch_force = uiaxes(app.Tab_2);
            title(app.Stretch_force, '推杆力')
            ylabel(app.Stretch_force, 'Force')
            zlabel(app.Stretch_force, 'Z')
            app.Stretch_force.XLim = [0 100];
            app.Stretch_force.YLim = [-50 50];
            app.Stretch_force.YTick = [-50 -25 0 25 50];
            app.Stretch_pos = uiaxes(app.Tab_2);
            title(app.Stretch_pos, '推杆位置')
            ylabel(app.Stretch_pos, 'Position')
            zlabel(app.Stretch_pos, 'Z')
            app.Stretch_pos.XLim = [0 100];
            app.Stretch_pos.YLim = [0 50];
            app.Heat_Press = uiaxes(app.Tab_2);
            title(app.Heat_Press, '热力图')
            zlabel(app.Heat_Press, 'Z')
            app.Heat_Press.XLim = [0 100];
            app.Heat_Press.YLim = [0 50];
            app.Heat_Press.XTick = [];
            app.Heat_Press.YTick = [];
            colormap(app.Heat_Press, 'jet')
            app.Stretch_State = uilabel(app.Tab_2);
            app.Stretch_State.Text = '推 杆 状 态';
            app.Lamp_8 = uilamp(app.Tab_2);
            app.Lamp_7 = uilamp(app.Tab_2);
            app.Lamp_6 = uilamp(app.Tab_2);
            app.Lamp_5 = uilamp(app.Tab_2);
            app.Lamp_4 = uilamp(app.Tab_2);
            app.Lamp_3 = uilamp(app.Tab_2);
            app.Lamp_2 = uilamp(app.Tab_2);
            app.Lamp_1 = uilamp(app.Tab_2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clinic_test0401

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end